classdef DQ_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        odq_varification  matlab.ui.Figure
        uipanel3          matlab.ui.container.Panel
        save_file         matlab.ui.control.Button
        save_WS           matlab.ui.control.Button
        uipanel1          matlab.ui.container.Panel
        KLabel            matlab.ui.control.Label
        PLabel            matlab.ui.control.Label
        text12            matlab.ui.control.Label
        text10            matlab.ui.control.Label
        text11            matlab.ui.control.Label
        start_sim         matlab.ui.control.Button
        edit_finaltime    matlab.ui.control.EditField
        edit_x0_2         matlab.ui.control.EditField
        edit_x0           matlab.ui.control.EditField
        edit_ref          matlab.ui.control.EditField
        axes_block        matlab.ui.control.UIAxes
        axes_diff         matlab.ui.control.UIAxes
        axes_v            matlab.ui.control.UIAxes
        axes_u            matlab.ui.control.UIAxes
        axes_zQ           matlab.ui.control.UIAxes
        uipanel2          matlab.ui.container.Panel
        wvLabel           matlab.ui.control.Label
        uvLabel           matlab.ui.control.Label
        val_gain_uv       matlab.ui.control.EditField
        text9             matlab.ui.control.Label
        text8             matlab.ui.control.Label
        text5             matlab.ui.control.Label
        text6             matlab.ui.control.Label
        text7             matlab.ui.control.Label
        text3             matlab.ui.control.Label
        text4             matlab.ui.control.Label
        text2             matlab.ui.control.Label
        val_UE            matlab.ui.control.EditField
        val_LE            matlab.ui.control.EditField
        val_Einf          matlab.ui.control.EditField
        val_ET            matlab.ui.control.EditField
        val_dim           matlab.ui.control.EditField
        val_gain_wv       matlab.ui.control.EditField
        val_stability     matlab.ui.control.EditField
        axes_hnkl         matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        odq_design
        odqdata
        output
        G
        T
        gamma
        Q
        E
        Hk
        gain
        d
        con
    end

    
    methods (Access = private)
        function block = create_GQ_block(app)
            block.G=rectangle(app.axes_block,'position',[5 6 2 1.5],'facecolor',[0.0 0.7 0.3]);
            block.Q=rectangle(app.axes_block,'position',[5 4.25 2 1.5],'facecolor',[1.0 1.0 0.0]);
            block.textG=text(app.axes_block,5.7,6.75,'$G$','fontsize',12,'interpreter','latex','color',[1 1 1]);
            block.textQ=text(app.axes_block,5.7,5.00,'$Q$','fontsize',12,'interpreter','latex','color',[0 0 0]);
            block.r(1)=line(app.axes_block,[ 4.50  5.00],[7.25 7.00],'linewidth',1,'color',[0 0 0]);
            block.r(2)=line(app.axes_block,[ 4.50  5.00],[6.75 7.00],'linewidth',1,'color',[0 0 0]);
            block.r(3)=line(app.axes_block,[ 1.00  5.00],[7.00 7.00],'linewidth',1,'color',[0 0 0]);
            block.u(1)=line(app.axes_block,[ 7.00  7.50],[5.00 5.25],'linewidth',1,'color',[0 0 1]);
            block.u(2)=line(app.axes_block,[ 7.00  7.50],[5.00 4.75],'linewidth',1,'color',[0 0 1]);
            block.u(3)=line(app.axes_block,[7.00 9.00 9.00 7.00],...
                            [ 6.50  6.50  5.00  5.00],'linewidth',1,'color',[0 0 1]);
            block.v(1)=line(app.axes_block,[ 4.50  5.00],[6.75 6.50],'linewidth',1,'color',[1 0 0]);
            block.v(2)=line(app.axes_block,[ 4.50  5.00],[6.25 6.50],'linewidth',1,'color',[1 0 0]);
            block.v(3)=line(app.axes_block,[ 5.00  3.00  3.00  5.00],...
                             [5.00  5.00  6.50  6.50],'linewidth',1,'color',[1 0 0]);
            block.z(1)=line(app.axes_block,[10.50 11.00],[7.25 7.00],'linewidth',1,'color',[0 0 0]);
            block.z(2)=line(app.axes_block,[10.50 11.00],[6.75 7.00],'linewidth',1,'color',[0 0 0]);
            block.z(3)=line(app.axes_block,[7.00 11.00],[7.00 7.00] ,'linewidth',1,'color',[0 0 0]);
            rectangle(app.axes_block,'position',[9.625 6.625 0.75 0.75],'Curvature',[1,1], ...
                'linewidth',1)
            line(app.axes_block,[10.2652,11],[7.2652,8],'linewidth',1,'color',[0 0 0])
            rectangle('position',[2.625 5.25 0.75 0.75],'Curvature',[1,1], ...
                'linewidth',1,'EdgeColor',[1 0 0])
            line(app.axes_block,[2.8215,2],[5.25,3],'linewidth',1,'color',[1 0 0])
            rectangle(app.axes_block,'position',[7.625 6.125 0.75 0.75],'Curvature',[1,1], ...
                'linewidth',1,'EdgeColor',[0 0 1])
            line(app.axes_block,[8,8,5],[6.825,7.5,8],'linewidth',1,'color',[0 0 1])
        end
        
        function block = create_fbiq_block(app)
            block.P=rectangle(app.axes_block,'position',[8 5.5 2 1.5],'facecolor',[0.7 0.3 1.0]);
            block.K=rectangle(app.axes_block,'position',[2 5.5 2 1.5],'facecolor',[0.0 0.7 0.3]);
            block.Q=rectangle(app.axes_block,'position',[5 5.5 2 1.5],'facecolor',[1.0 1.0 0.0]);
            block.textP=text(app.axes_block,8.5,6.25,'$P$','fontsize',14,'interpreter','latex','color',[1 1 1]);
            block.textK=text(app.axes_block,2.5,6.25,'$K$','fontsize',14,'interpreter','latex','color',[1 1 1]);
            block.textQ=text(app.axes_block,5.5,6.25,'$Q$','fontsize',14,'interpreter','latex','color',[0 0 0]);
            block.r(1)=line(app.axes_block,[ 1.50  2.00],[6.75 6.50],'linewidth',1,'color',[0 0 0]);
            block.r(2)=line(app.axes_block,[ 1.50  2.00],[6.25 6.50],'linewidth',1,'color',[0 0 0]);
            block.r(3)=line(app.axes_block,[ 0.00  2.00],[6.50 6.50],'linewidth',1,'color',[0 0 0]);
            block.u(1)=line(app.axes_block,[ 4.50  5.00],[6.50 6.25],'linewidth',1,'color',[0 0 1]);
            block.u(2)=line(app.axes_block,[ 4.50  5.00],[6.00 6.25],'linewidth',1,'color',[0 0 1]);
            block.u(3)=line(app.axes_block,[ 4.00  5.00],[6.25 6.25],'linewidth',1,'color',[0 0 1]);
            block.v(1)=line(app.axes_block,[ 7.50  8.00],[6.50 6.25],'linewidth',1,'color',[1 0 0]);
            block.v(2)=line(app.axes_block,[ 7.50  8.00],[6.00 6.25],'linewidth',1,'color',[1 0 0]);
            block.v(3)=line(app.axes_block,[ 7.00  8.00],[6.25 6.25],'linewidth',1,'color',[1 0 0]);
            block.z(1)=line(app.axes_block,[11.50 12.00],[6.75 6.50],'linewidth',1,'color',[0 0 0]);
            block.z(2)=line(app.axes_block,[11.50 12.00],[6.25 6.50],'linewidth',1,'color',[0 0 0]);
            block.z(3)=line(app.axes_block,[10.00 12.00],[6.50 6.50],'linewidth',1,'color',[0 0 0]);
            block.y(1)=line(app.axes_block,[ 1.50  2.00],[6.25 6.00],'linewidth',1,'color',[0 0 0]);
            block.y(2)=line(app.axes_block,[ 1.50  2.00],[5.75 6.00],'linewidth',1,'color',[0 0 0]);
            block.y(3)=line(app.axes_block,[10.00 10.50 10.50 1.25 1.25 2.00],...
                   [ 6.00  6.00  4.50 4.50 6.00 6.00],'linewidth',1,'color',[0 0 0]);
            % rectangle('position',[9.625 6.625 0.75 0.75],'Curvature',[1,1], ...
            %     'linewidth',1)
            line(app.axes_block,[11,11],[6.5,8],'linewidth',1,'color',[0 0 0])
            % rectangle('position',[2.625 5.25 0.75 0.75],'Curvature',[1,1], ...
            %     'linewidth',1,'EdgeColor',[1 0 0])
            line(app.axes_block,[7.25,7.25,2],[6.25,4,3],'linewidth',1,'color',[1 0 0])
            % rectangle('position',[7.625 6.125 0.75 0.75],'Curvature',[1,1], ...
            %     'linewidth',1,'EdgeColor',[0 0 1])
            line(app.axes_block,[4.25,4.25,3],[6.25,7.5,8],'linewidth',1,'color',[0 0 1])
        end
        
        function block = create_fboq_block(app)
            block.P=rectangle(app.axes_block,'position',[8 6 2 1.5],'facecolor',[0.7 0.3 1.0]);
            block.K=rectangle(app.axes_block,'position',[2 6 2 1.5],'facecolor',[0.0 0.7 0.3]);
            block.Q=rectangle(app.axes_block,'position',[5 4.25 2 1.5],'facecolor',[1.0 1.0 0.0]);
            block.textP=text(app.axes_block,8.5,6.75,'$P$','fontsize',14,'interpreter','latex','color',[1 1 1]);
            block.textK=text(app.axes_block,2.5,6.75,'$K$','fontsize',14,'interpreter','latex','color',[1 1 1]);
            block.textQ=text(app.axes_block,5.5,5.00,'$Q$','fontsize',14,'interpreter','latex','color',[0 0 0]);
            block.r(1)=line(app.axes_block,[ 1.50  2.00],[7.25 7.00],'linewidth',1,'color',[0 0 0]);
            block.r(2)=line(app.axes_block,[ 1.50  2.00],[6.75 7.00],'linewidth',1,'color',[0 0 0]);
            block.r(3)=line(app.axes_block,[ 0.00  2.00],[7.00 7.00],'linewidth',1,'color',[0 0 0]);
            block.u(1)=line(app.axes_block,[ 7.00  7.50],[5.00 5.25],'linewidth',1,'color',[0 0 1]);
            block.u(2)=line(app.axes_block,[ 7.00  7.50],[5.00 4.75],'linewidth',1,'color',[0 0 1]);
            block.u(3)=line(app.axes_block,[10.00 10.50 10.50  7.00],...
                            [ 6.50  6.50  5.00  5.00],'linewidth',1,'color',[0 0 1]);
            block.v(1)=line(app.axes_block,[ 1.50  2.00],[6.75 6.50],'linewidth',1,'color',[1 0 0]);
            block.v(2)=line(app.axes_block,[ 1.50  2.00],[6.25 6.50],'linewidth',1,'color',[1 0 0]);
            block.v(3)=line(app.axes_block,[ 5.00  1.25  1.25  2.00],...
                             [5.00  5.00  6.50  6.50],'linewidth',1,'color',[1 0 0]);
            block.z(1)=line(app.axes_block,[11.50 12.00],[7.25 7.00],'linewidth',1,'color',[0 0 0]);
            block.z(2)=line(app.axes_block,[11.50 12.00],[6.75 7.00],'linewidth',1,'color',[0 0 0]);
            block.z(3)=line(app.axes_block,[10.00 12.00],[7.00 7.00],'linewidth',1,'color',[0 0 0]);
            block.w(1)=line(app.axes_block,[ 7.50  8.00],[7.00 6.75],'linewidth',1,'color',[0 0 0]);
            block.w(2)=line(app.axes_block,[ 7.50  8.00],[6.50 6.75],'linewidth',1,'color',[0 0 0]);
            block.w(3)=line(app.axes_block,[ 4.00  8.00],[6.75 6.75],'linewidth',1,'color',[0 0 0]);
            rectangle(app.axes_block,'Position',[10.625 6.625 0.75 0.75],'Curvature',[1,1], ...
                'linewidth',1)
            line([11,11],[7.375,8],'linewidth',1,'color',[0 0 0])
            rectangle(app.axes_block,'position',[2.625 4.625 0.75 0.75],'Curvature',[1,1], ...
                'linewidth',1,'EdgeColor',[1 0 0])
            line(app.axes_block,[2.8125,2],[4.6752,3],'linewidth',1,'color',[1 0 0])
            rectangle(app.axes_block,'position',[8.625 4.625 0.75 0.75],'Curvature',[1,1], ...
                'linewidth',1,'EdgeColor',[0 0 1])
            line(app.axes_block,[8.7348,4],[5.2652,8],'linewidth',1,'color',[0 0 1])
        end
        
        function block = create_ff_block(app)
            block.P=rectangle(app.axes_block,'position',[7 5 2 1.5],'facecolor',[0.7 0.3 1.0]);
            block.Q=rectangle(app.axes_block,'position',[3 5 2 1.5],'facecolor',[1.0 1.0 0.0]);
            block.textP=text(app.axes_block,7.5,5.75,'$P$','fontsize',14,'interpreter','latex','color',[1 1 1]);
            block.textQ=text(app.axes_block,3.5,5.75,'$Q$','fontsize',14,'interpreter','latex','color',[0 0 0]);
            block.z(1)=line(app.axes_block,[10.5 11.0],[6.00 5.75],'linewidth',1,'color',[0 0 0]);
            block.z(2)=line(app.axes_block,[10.5 11.0],[5.50 5.75],'linewidth',1,'color',[0 0 0]);
            block.z(3)=line(app.axes_block,[9.0 11.0],[5.75 5.75] ,'linewidth',1,'color',[0 0 0]);
            block.v(1)=line(app.axes_block,[6.5 7.0] ,[6.00 5.75] ,'linewidth',1,'color',[1 0 0]);
            block.v(2)=line(app.axes_block,[6.5 7.0] ,[5.50 5.75] ,'linewidth',1,'color',[1 0 0]);
            block.v(3)=line(app.axes_block,[5.0 7.0] ,[5.75 5.75] ,'linewidth',1,'color',[1 0 0]);
            block.u(1)=line(app.axes_block,[2.5 3.0] ,[6.00 5.75] ,'linewidth',1,'color',[0 0 1]);
            block.u(2)=line(app.axes_block,[2.5 3.0] ,[5.50 5.75] ,'linewidth',1,'color',[0 0 1]);
            block.u(3)=line(app.axes_block,[1.0 3.0] ,[5.75 5.75] ,'linewidth',1,'color',[0 0 1]);
            rectangle(app.axes_block,'Position',[9.5 5.375 0.75 0.75],'Curvature',[1,1], ...
                'linewidth',1)
            line(app.axes_ff,[9.875,9.875,11],[6.125,6.5,8],'linewidth',1,'color',[0 0 0])
            rectangle(app.axes_block,'position',[5.5 5.375 0.75 0.75],'Curvature',[1,1], ...
                'linewidth',1,'EdgeColor',[1 0 0])
            line(app.axes_ff,[5.875,5.875,2],[5.375,4,3],'linewidth',1,'color',[1 0 0])
            rectangle(app.axes_block,'position',[1.5 5.375 0.75 0.75],'Curvature',[1,1], ...
                'linewidth',1,'EdgeColor',[0 0 1])
            line(app.axes_block,[1.875,1.875,1.5],[6.125,6.5,8],'linewidth',1,'color',[0 0 1])
        end
        
        % Update components that require runtime configuration
        function addRuntimeConfigurations(app)
            %{
            loadedData = load('DQ_App.mat', 'myData');
            app.odqdata.G = loadedData.myData.G;
            app.odqdata.T = loadedData.myData.T;
            app.odqdata.gamma = loadedData.myData.gamma;
            app.odqdata.Q = loadedData.myData.Q;
            app.odqdata.E = loadedData.myData.E;
            app.odqdata.Hk = loadedData.myData.Hk;
            app.odqdata.gain = loadedData.myData.gain;
            app.odqdata.d = loadedData.myData.d;
            app.odqdata.con = loadedData.myData.con;
            %}
            % Set component properties that require runtime configuration
            app.odq_varification.DeleteFcn = createCallbackFcn(app, @figure1_DeleteFcn, true);
            %app.edit_ref.UserData = componentData.edit_ref.UserData;
            %app.edit_finaltime.UserData = componentData.edit_finaltime.UserData;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function DQ_StartupFcn(app, odq_varification, varargin)
            % --- Executes just before DQ is made visible.
            
            % Add runtime required configuration - Added by Migration Tool
            addRuntimeConfigurations(app);
            
            % Ensure that the app appears on screen when run
            movegui(app.odq_varification, 'onscreen');
            
            % Choose default command line output for DQ
            app.output = app.odq_varification;
            
            %{
            odqgui_input = find(strcmp(varargin, 'ODQ_Designer'));
            if ~isempty(odqgui_input)
                app.odq_design = varargin{odqgui_input+1};
            else
                errordlg('Please design optimal dynamic quantizer','ERROR','modal');
                error('ODQ ERROR')
            end
            %}
          
            % UIWAIT makes DQ wait for user response (see UIRESUME)
            %uiwait(app.odq_varification);
            loadedData = load('DQ_App.mat', 'myData');

            app.odqdata = loadedData.myData.odqdata;
            app.G    = loadedData.myData.G;
            app.Q    = loadedData.myData.Q;
            app.d    = loadedData.myData.d;
            app.gain = loadedData.myData.gain;
            app.E    = loadedData.myData.E;
            app.Hk   = loadedData.myData.Hk;
            app.con  = loadedData.myData.con;
            
            disp('G');
            disp(app.G);

            stb=odqstb(app.Q);
            if size(app.Q.a,1)>1000
                Einf='skipped';
            else
                Einf = odqcost(app.G,app.Q,app.d,inf);
            end
            staticQ.a  = 0;
            staticQ.b1 = zeros(1,size(app.G.c2,1));
            staticQ.b2 = zeros(1,size(app.G.c2,1));
            staticQ.c  = zeros(size(app.G.c2,1),1);
            E_upper = odqcost(app.G,staticQ,app.d,inf);
            E_low   = norm(app.G.c1*app.G.b2,inf)*app.d/2;
            
            if stb==1
                app.val_stability.Value='STABLE';
            else
                app.val_stability.Value='UNSTABLE';
            end
            app.val_gain_wv.Value = string(app.gain.wv);
            app.val_dim.Value  = string(size(app.Q.a,1));
            app.val_ET.Value   = string(app.E);
            app.val_Einf.Value = string(Einf);
            app.val_LE.Value   = string(E_low);
            app.val_UE.Value   = string(E_upper);

            singular_xrange=0:size(app.Hk.S,1)-1;
            stairs(app.axes_hnkl,singular_xrange,diag(app.Hk.S),'LineWidth',2);
            set(app.axes_hnkl,'Yscale','log','YGrid','on');

            redline=zeros(1,size(app.Hk.S,1));
            for k=1:size(app.Hk.S,1)-1
                if app.Hk.S(k+1,k+1)/app.Hk.S(k,k)<0.1
                    redline(k)=1;
                end
            end
            if max(redline)==1
            axes(app.axes_hnkl)
            singular_yrange=get(gca,'YLim');
                xredline=find(redline);
                for k=1:length(xredline)
                    line(app.axes_hnkl,[xredline(k) xredline(k)],singular_yrange,'color',[1 0 0])
                end
            set(app.axes_hnkl,'XTick',xredline,'XTickLabel',xredline);
            %set(app.axes_hnkl,'menubar','none','toolbar','none')
            %set(gcf,'menubar','figure','toolbar','figure')
            end
            ylabel(app.axes_u   ,'Quantizer input'  ,'FontSize',10)
            ylabel(app.axes_v   ,'Quantizer output' ,'FontSize',10)
            ylabel(app.axes_zQ  ,'Output','FontSize',10)
            ylabel(app.axes_diff,'Output difference','FontSize',10)
            
            if strcmp(loadedData.myData.con,'ff') || strcmp(loadedData.myData.con,'GQ')
                app.edit_x0_2.Enable = 'off';
            end
            
            axes(app.axes_block);
            if strcmp(loadedData.myData.con,'ff')
                create_ff_block(app);
            elseif strcmp(loadedData.myData.con,'fbiq')
                create_fbiq_block(app);
            elseif strcmp(loadedData.myData.con,'fboq')
                create_fboq_block(app);
            elseif strcmp(loadedData.myData.con,'GQ')
                create_GQ_block(app);
            end
        end

        % Value changed function: edit_finaltime
        function edit_finaltime_Callback(app, event)
            kFinal=str2double(app.edit_finaltime.Value);
            app.slider_finaltime.Value=kFinal;
            app.edit_finaltime.UserData=kFinal;
        end

        % Callback function
        function figure1_DeleteFcn(app, event)
            app.odqdata=app.odq_varification.UserData;
            app.odqdata.flg.DQopen=0;
            app.odq_varification.UserData=app.odqdata;
        end

        % Button pushed function: save_WS
        function save_WS_Callback(app, event)
            app.odqdata=app.odq_varification.UserData;
            val_name=inputdlg('Variable name in workspace');
            if ~isempty(val_name{1})
                assignin('base',val_name{1},app.Q)
                fprintf('Quantizer exported in Workspace!\n');
            end
        end

        % Button pushed function: save_file
        function save_file_Callback(app, event)
            app.odqdata=app.odq_varification.UserData;
            val_name=inputdlg('Variable name in workspace');
            if ~isempty(val_name{1})
                eval([val_name{1} '=' 'app.Q']);
                uisave(val_name{1})
            end
        end

        % Callback function
        function slider_finaltime_Callback(app, event)
            kFinal=app.slider_finaltime.Value;
            %set(handles.edit_finaltime,'String',kFinal,'UserData',kFinal)
        end

        % Button pushed function: start_sim
        function start_sim_Callback(app, event)
            % --- Executes on button press in start_sim.
            
            
            % app.odqdata = app.odq_varification.UserData;
            % app.G = app.odqdata.G;
            % app.Q = app.odqdata.Q;
            % app.d = app.odqdata.d;
            
            Rt=app.edit_ref.Value;
            x0_P=app.edit_x0.Value;
            x0_K=app.edit_x0_2.Value;
            kFinal=str2double(app.edit_finaltime.Value);
            x  = zeros( size(app.G.a ,1) , kFinal+1 );
            xQ = zeros( size(app.G.a ,1) , kFinal+1 );
            z  = zeros( size(app.G.c1,1) , kFinal+1 );
            zQ = zeros( size(app.G.c1,1) , kFinal+1 );
            u  = zeros( size(app.G.c2,1) , kFinal+1 );
            uQ = zeros( size(app.G.c2,1) , kFinal+1 );
            xi = zeros( size(app.Q.a ,1) , kFinal+1 );
            v  = zeros( size(app.Q.c ,1) , kFinal+1 );
            
            k = 0:kFinal;
            flg.r_work  = 0;
            flg.x0_P_work = 0;
            flg.x0_K_work = 0;
            work_val = evalin('base','who');
            for i = 1:size(work_val)
                if strcmp(work_val(i,:),Rt)==1
                    r=evalin('base',Rt);
                    flg.r_work=1;
                end
                if strcmp(work_val(i,:),x0_P)==1
                    x0_P=evalin('base',x0_K);
                    flg.x0_P_work=1;
                end
                if strcmp(work_val(i,:),x0_K)==1
                    x0_K=evalin('base',x0_K);
                    flg.x0_K_work=1;
                end
            end
            if flg.r_work==0
                r = eval(Rt);
            end
            if isscalar(r)==1
                r = r*ones(1,kFinal+1);
            end
            if flg.x0_P_work==1
                x0_P2 = x0_P;
            else
                x0_P2 = eval(x0_P);
            end
            if flg.x0_K_work==1
                x0_K2 = x0_K;
            else
                x0_K2 = eval(x0_K);
            end
            if strcmp(app.odqdata.connection,'ff') || strcmp(app.odqdata.connection,'GQ')
                x(:,1)  = x0_P2;
                xQ(:,1) = x0_P2;
            else
                x(:,1)  = [x0_P2;x0_K2];
                xQ(:,1) = [x0_P2;x0_K2];
            end
            
            %%% start simulation %%%
            for i=1:kFinal+1
                z(:,i)  = app.G.c1*x(:,i)  + app.G.d1*r(:,i);
                zQ(:,i) = app.G.c1*xQ(:,i) + app.G.d1*r(:,i);
                u(:,i)  = app.G.c2*x(:,i)  + app.G.d2*r(:,i);
                uQ(:,i) = app.G.c2*xQ(:,i) + app.G.d2*r(:,i);
                v(:,i)  = app.d*round( ( app.Q.c*xi(:,i) + uQ(:,i) )/app.d );
                xi(:,i+1) = app.Q.a*xi(:,i) + app.Q.b1*uQ(:,i) + app.Q.b2*v(:,i);
                x(:,i+1)  = app.G.a*x(:,i)  + app.G.b1*r(:,i) + app.G.b2*u(:,i);
                xQ(:,i+1) = app.G.a*xQ(:,i) + app.G.b1*r(:,i) + app.G.b2*v(:,i);
            end
            stairs(app.axes_u   ,k,u'    );
            %axis(handles.axes_u,[0 kFinal min(u) max(u)])
            stairs(app.axes_v   ,k,v'    );
            stairs(app.axes_zQ  ,k,zQ'   );
            stairs(app.axes_diff,k,zQ'-z');
            ylabel(app.axes_u   ,'Quantizer input'  ,'FontSize',10)
            ylabel(app.axes_v   ,'Quantizer output' ,'FontSize',10)
            ylabel(app.axes_zQ  ,'Output'           ,'FontSize',10)
            ylabel(app.axes_diff,'Output difference','FontSize',10)
            set(app.axes_u   ,'XGrid','on','YGrid','on')
            set(app.axes_v   ,'XGrid','on','YGrid','on')
            set(app.axes_zQ  ,'XGrid','on','YGrid','on')
            set(app.axes_diff,'XGrid','on','YGrid','on')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.odq_varification = uifigure('Visible', 'off');
            colormap(app.odq_varification, 'parula');
            app.odq_varification.Name = 'Verification Window';
            app.odq_varification.Tag = 'odq_varification';
            app.uipanel2 = uipanel(app.odq_varification);
            app.uipanel2.Title = 'Designed Quantizer';
            app.uipanel2.Tag = 'uipanel2';
            app.axes_hnkl = uiaxes(app.uipanel2);
            app.axes_hnkl.CLim = [1 2];
            app.axes_hnkl.YScale = 'log';
            app.axes_hnkl.Tag = 'axes_hnkl';
            app.val_stability = uieditfield(app.uipanel2, 'text');
            app.val_stability.Tag = 'val_stability';
            app.val_stability.Enable = 'off';
            app.val_gain_wv = uieditfield(app.uipanel2, 'text');
            app.val_gain_wv.Tag = 'val_gain_wv';
            app.val_gain_wv.Enable = 'off';
            app.val_dim = uieditfield(app.uipanel2, 'text');
            app.val_dim.Tag = 'val_dim';
            app.val_dim.Enable = 'off';
            app.val_ET = uieditfield(app.uipanel2, 'text');
            app.val_ET.Tag = 'val_ET';
            app.val_ET.Enable = 'off';
            app.val_Einf = uieditfield(app.uipanel2, 'text');
            app.val_Einf.Tag = 'val_Einf';
            app.val_Einf.Enable = 'off';
            app.val_LE = uieditfield(app.uipanel2, 'text');
            app.val_LE.Tag = 'val_LE';
            app.val_LE.Enable = 'off';
            app.val_UE = uieditfield(app.uipanel2, 'text');
            app.val_UE.Tag = 'val_UE';
            app.val_UE.Enable = 'off';
            app.text2 = uilabel(app.uipanel2);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Dimention';
            app.text4 = uilabel(app.uipanel2);
            app.text4.Tag = 'text4';
            app.text4.Text = 'Stability';
            app.text3 = uilabel(app.uipanel2);
            app.text3.Tag = 'text3';
            app.text3.Text = 'Quantizer gain';
            app.text7 = uilabel(app.uipanel2);
            app.text7.Tag = 'text7';
            app.text7.Text = 'E(T,Q)';
            app.text6 = uilabel(app.uipanel2);
            app.text6.Tag = 'text6';
            app.text6.Text = 'E(Inf,Q)';
            app.text5 = uilabel(app.uipanel2);
            app.text5.Tag = 'text5';
            app.text5.Text = {'Lower bound'; 'of E(Inf,Q)'};
            app.text8 = uilabel(app.uipanel2);
            app.text8.Tag = 'text8';
            app.text8.Text = {'Upper bound'; 'of E(Inf,Q)'};
            app.text9 = uilabel(app.uipanel2);
            app.text9.Tag = 'text9';
            app.text9.Text = 'Singular value distribution';
            app.val_gain_uv = uieditfield(app.uipanel2, 'text');
            app.val_gain_uv.Tag = 'val_gain_uv';
            app.val_gain_uv.Enable = 'off';
            app.uvLabel = uilabel(app.uipanel2);
            app.uvLabel.Text = 'u -> v';
            app.wvLabel = uilabel(app.uipanel2);
            app.wvLabel.Text = 'w -> v';
            app.uipanel1 = uipanel(app.odq_varification);
            app.uipanel1.Title = 'Simulation';
            app.uipanel1.Tag = 'uipanel1';
            app.axes_zQ = uiaxes(app.uipanel1);
            app.axes_zQ.Tag = 'axes_zQ';
            app.axes_u = uiaxes(app.uipanel1);
            app.axes_u.Tag = 'axes_u';
            app.axes_v = uiaxes(app.uipanel1);
            app.axes_v.Tag = 'axes_v';
            app.axes_diff = uiaxes(app.uipanel1);
            app.axes_diff.Tag = 'axes_diff';
            app.axes_block = uiaxes(app.uipanel1);
            app.axes_block.XLim = [0 12];
            app.axes_block.YLim = [3 8];
            app.axes_block.Tag = 'axes_block';
            app.axes_block.Visible = 'off';
            app.edit_ref = uieditfield(app.uipanel1, 'text');
            app.edit_ref.Tag = 'edit_ref';
            app.edit_ref.Value = 'sin(k)+0.5*cos(k)';
            app.edit_x0 = uieditfield(app.uipanel1, 'text');
            app.edit_x0.Tag = 'edit_x0';
            app.edit_x0.Value = '[0;0;0.1;0]';
            app.edit_x0_2 = uieditfield(app.uipanel1, 'text');
            app.edit_x0_2.Tag = 'edit_x0_2';
            app.edit_x0_2.Value = '0';
            app.edit_finaltime = uieditfield(app.uipanel1, 'text');
            app.edit_finaltime.ValueChangedFcn = createCallbackFcn(app, @edit_finaltime_Callback, true);
            app.edit_finaltime.Tag = 'edit_finaltime';
            app.edit_finaltime.Value = '100';
            app.start_sim = uibutton(app.uipanel1, 'push');
            app.start_sim.ButtonPushedFcn = createCallbackFcn(app, @start_sim_Callback, true);
            app.start_sim.Tag = 'start_sim';
            app.start_sim.Text = 'Start';
            app.text11 = uilabel(app.uipanel1);
            app.text11.Tag = 'text11';
            app.text11.Text = 'x(0) (Initial state)';
            app.text10 = uilabel(app.uipanel1);
            app.text10.Tag = 'text10';
            app.text10.Text = 'R[k] (Reference input)';
            app.text12 = uilabel(app.uipanel1);
            app.text12.Tag = 'text12';
            app.text12.Text = 'Simlation Time';
            app.PLabel = uilabel(app.uipanel1);
            app.PLabel.Text = 'P';
            app.KLabel = uilabel(app.uipanel1);
            app.KLabel.Text = 'K';
            app.uipanel3 = uipanel(app.odq_varification);
            app.uipanel3.Title = 'E x p o r t';
            app.uipanel3.Tag = 'uipanel3';
            app.save_WS = uibutton(app.uipanel3, 'push');
            app.save_WS.ButtonPushedFcn = createCallbackFcn(app, @save_WS_Callback, true);
            app.save_WS.Tag = 'save_WS';
            app.save_WS.Text = 'To Workspace';
            app.save_file = uibutton(app.uipanel3, 'push');
            app.save_file.ButtonPushedFcn = createCallbackFcn(app, @save_file_Callback, true);
            app.save_file.Tag = 'save_file';
            app.save_file.Text = 'To File';
            % Show the figure after all components are created
            app.odq_varification.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DQ_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.odq_varification)

                % Execute the startup function
                runStartupFcn(app, @(app)DQ_StartupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.odq_varification)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.odq_varification)
        end
    end
end