classdef DQ_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1         matlab.ui.Figure
        uipanel3        matlab.ui.container.Panel
        save_file       matlab.ui.control.Button
        save_WS         matlab.ui.control.Button
        uipanel1        matlab.ui.container.Panel
        text12          matlab.ui.control.Label
        text10          matlab.ui.control.Label
        text11          matlab.ui.control.Label
        start_sim       matlab.ui.control.Button
        edit_finaltime  matlab.ui.control.EditField
        edit_x0_2       matlab.ui.control.EditField
        edit_x0         matlab.ui.control.EditField
        edit_ref        matlab.ui.control.EditField
        axes_block      matlab.ui.control.UIAxes
        axes_diff       matlab.ui.control.UIAxes
        axes_v          matlab.ui.control.UIAxes
        axes_u          matlab.ui.control.UIAxes
        axes_zQ         matlab.ui.control.UIAxes
        uipanel2        matlab.ui.container.Panel
        val_gain_uv     matlab.ui.control.EditField
        text9           matlab.ui.control.Label
        text8           matlab.ui.control.Label
        text5           matlab.ui.control.Label
        text6           matlab.ui.control.Label
        text7           matlab.ui.control.Label
        text3           matlab.ui.control.Label
        text4           matlab.ui.control.Label
        text2           matlab.ui.control.Label
        val_UE          matlab.ui.control.EditField
        val_LE          matlab.ui.control.EditField
        val_Einf        matlab.ui.control.EditField
        val_ET          matlab.ui.control.EditField
        val_dim         matlab.ui.control.EditField
        val_gain_wv     matlab.ui.control.EditField
        val_stability   matlab.ui.control.EditField
        axes_hnkl       matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        function block = create_GQ_block(app)
            block.G=rectangle('position',[5 6 2 1.5],'facecolor',[0.0 0.7 0.3]);
            block.Q=rectangle('position',[5 4.25 2 1.5],'facecolor',[1.0 1.0 0.0]);
            block.textG=text(5.7,6.75,'$G$','fontsize',12,'interpreter','latex','color',[1 1 1]);
            block.textQ=text(5.7,5.00,'$Q$','fontsize',12,'interpreter','latex','color',[0 0 0]);
            block.r(1)=line([ 4.50  5.00],[7.25 7.00],'linewidth',1,'color',[0 0 0]);
            block.r(2)=line([ 4.50  5.00],[6.75 7.00],'linewidth',1,'color',[0 0 0]);
            block.r(3)=line([ 1.00  5.00],[7.00 7.00],'linewidth',1,'color',[0 0 0]);
            block.u(1)=line([ 7.00  7.50],[5.00 5.25],'linewidth',1,'color',[0 0 1]);
            block.u(2)=line([ 7.00  7.50],[5.00 4.75],'linewidth',1,'color',[0 0 1]);
            block.u(3)=line([7.00 9.00 9.00 7.00],...
                            [ 6.50  6.50  5.00  5.00],'linewidth',1,'color',[0 0 1]);
            block.v(1)=line([ 4.50  5.00],[6.75 6.50],'linewidth',1,'color',[1 0 0]);
            block.v(2)=line([ 4.50  5.00],[6.25 6.50],'linewidth',1,'color',[1 0 0]);
            block.v(3)=line([ 5.00  3.00  3.00  5.00],...
                             [5.00  5.00  6.50  6.50],'linewidth',1,'color',[1 0 0]);
            block.z(1)=line([10.50 11.00],[7.25 7.00],'linewidth',1,'color',[0 0 0]);
            block.z(2)=line([10.50 11.00],[6.75 7.00],'linewidth',1,'color',[0 0 0]);
            block.z(3)=line([7.00 11.00],[7.00 7.00] ,'linewidth',1,'color',[0 0 0]);
            rectangle('position',[9.625 6.625 0.75 0.75],'Curvature',[1,1], ...
                'linewidth',1)
            line([10.2652,11],[7.2652,8],'linewidth',1,'color',[0 0 0])
            rectangle('position',[2.625 5.25 0.75 0.75],'Curvature',[1,1], ...
                'linewidth',1,'EdgeColor',[1 0 0])
            line([2.8215,2],[5.25,3],'linewidth',1,'color',[1 0 0])
            rectangle('position',[7.625 6.125 0.75 0.75],'Curvature',[1,1], ...
                'linewidth',1,'EdgeColor',[0 0 1])
            line([8,8,5],[6.825,7.5,8],'linewidth',1,'color',[0 0 1])
        end
        
        function block = create_fbiq_block(app)
            block.P=rectangle('position',[8 5.5 2 1.5],'facecolor',[0.7 0.3 1.0]);
            block.K=rectangle('position',[2 5.5 2 1.5],'facecolor',[0.0 0.7 0.3]);
            block.Q=rectangle('position',[5 5.5 2 1.5],'facecolor',[1.0 1.0 0.0]);
            block.textP=text(8.5,6.25,'$P$','fontsize',14,'interpreter','latex','color',[1 1 1]);
            block.textK=text(2.5,6.25,'$K$','fontsize',14,'interpreter','latex','color',[1 1 1]);
            block.textQ=text(5.5,6.25,'$Q$','fontsize',14,'interpreter','latex','color',[0 0 0]);
            block.r(1)=line([ 1.50  2.00],[6.75 6.50],'linewidth',1,'color',[0 0 0]);
            block.r(2)=line([ 1.50  2.00],[6.25 6.50],'linewidth',1,'color',[0 0 0]);
            block.r(3)=line([ 0.00  2.00],[6.50 6.50],'linewidth',1,'color',[0 0 0]);
            block.u(1)=line([ 4.50  5.00],[6.50 6.25],'linewidth',1,'color',[0 0 1]);
            block.u(2)=line([ 4.50  5.00],[6.00 6.25],'linewidth',1,'color',[0 0 1]);
            block.u(3)=line([ 4.00  5.00],[6.25 6.25],'linewidth',1,'color',[0 0 1]);
            block.v(1)=line([ 7.50  8.00],[6.50 6.25],'linewidth',1,'color',[1 0 0]);
            block.v(2)=line([ 7.50  8.00],[6.00 6.25],'linewidth',1,'color',[1 0 0]);
            block.v(3)=line([ 7.00  8.00],[6.25 6.25],'linewidth',1,'color',[1 0 0]);
            block.z(1)=line([11.50 12.00],[6.75 6.50],'linewidth',1,'color',[0 0 0]);
            block.z(2)=line([11.50 12.00],[6.25 6.50],'linewidth',1,'color',[0 0 0]);
            block.z(3)=line([10.00 12.00],[6.50 6.50],'linewidth',1,'color',[0 0 0]);
            block.y(1)=line([ 1.50  2.00],[6.25 6.00],'linewidth',1,'color',[0 0 0]);
            block.y(2)=line([ 1.50  2.00],[5.75 6.00],'linewidth',1,'color',[0 0 0]);
            block.y(3)=line([10.00 10.50 10.50 1.25 1.25 2.00],...
                   [ 6.00  6.00  4.50 4.50 6.00 6.00],'linewidth',1,'color',[0 0 0]);
            % rectangle('position',[9.625 6.625 0.75 0.75],'Curvature',[1,1], ...
            %     'linewidth',1)
            line([11,11],[6.5,8],'linewidth',1,'color',[0 0 0])
            % rectangle('position',[2.625 5.25 0.75 0.75],'Curvature',[1,1], ...
            %     'linewidth',1,'EdgeColor',[1 0 0])
            line([7.25,7.25,2],[6.25,4,3],'linewidth',1,'color',[1 0 0])
            % rectangle('position',[7.625 6.125 0.75 0.75],'Curvature',[1,1], ...
            %     'linewidth',1,'EdgeColor',[0 0 1])
            line([4.25,4.25,3],[6.25,7.5,8],'linewidth',1,'color',[0 0 1])
        end
        
        function block = create_fboq_block(app)
            block.P=rectangle('position',[8 6 2 1.5],'facecolor',[0.7 0.3 1.0]);
            block.K=rectangle('position',[2 6 2 1.5],'facecolor',[0.0 0.7 0.3]);
            block.Q=rectangle('position',[5 4.25 2 1.5],'facecolor',[1.0 1.0 0.0]);
            block.textP=text(8.5,6.75,'$P$','fontsize',14,'interpreter','latex','color',[1 1 1]);
            block.textK=text(2.5,6.75,'$K$','fontsize',14,'interpreter','latex','color',[1 1 1]);
            block.textQ=text(5.5,5.00,'$Q$','fontsize',14,'interpreter','latex','color',[0 0 0]);
            block.r(1)=line([ 1.50  2.00],[7.25 7.00],'linewidth',1,'color',[0 0 0]);
            block.r(2)=line([ 1.50  2.00],[6.75 7.00],'linewidth',1,'color',[0 0 0]);
            block.r(3)=line([ 0.00  2.00],[7.00 7.00],'linewidth',1,'color',[0 0 0]);
            block.u(1)=line([ 7.00  7.50],[5.00 5.25],'linewidth',1,'color',[0 0 1]);
            block.u(2)=line([ 7.00  7.50],[5.00 4.75],'linewidth',1,'color',[0 0 1]);
            block.u(3)=line([10.00 10.50 10.50  7.00],...
                            [ 6.50  6.50  5.00  5.00],'linewidth',1,'color',[0 0 1]);
            block.v(1)=line([ 1.50  2.00],[6.75 6.50],'linewidth',1,'color',[1 0 0]);
            block.v(2)=line([ 1.50  2.00],[6.25 6.50],'linewidth',1,'color',[1 0 0]);
            block.v(3)=line([ 5.00  1.25  1.25  2.00],...
                             [5.00  5.00  6.50  6.50],'linewidth',1,'color',[1 0 0]);
            block.z(1)=line([11.50 12.00],[7.25 7.00],'linewidth',1,'color',[0 0 0]);
            block.z(2)=line([11.50 12.00],[6.75 7.00],'linewidth',1,'color',[0 0 0]);
            block.z(3)=line([10.00 12.00],[7.00 7.00],'linewidth',1,'color',[0 0 0]);
            block.w(1)=line([ 7.50  8.00],[7.00 6.75],'linewidth',1,'color',[0 0 0]);
            block.w(2)=line([ 7.50  8.00],[6.50 6.75],'linewidth',1,'color',[0 0 0]);
            block.w(3)=line([ 4.00  8.00],[6.75 6.75],'linewidth',1,'color',[0 0 0]);
            rectangle('Position',[10.625 6.625 0.75 0.75],'Curvature',[1,1], ...
                'linewidth',1)
            line([11,11],[7.375,8],'linewidth',1,'color',[0 0 0])
            rectangle('position',[2.625 4.625 0.75 0.75],'Curvature',[1,1], ...
                'linewidth',1,'EdgeColor',[1 0 0])
            line([2.8125,2],[4.6752,3],'linewidth',1,'color',[1 0 0])
            rectangle('position',[8.625 4.625 0.75 0.75],'Curvature',[1,1], ...
                'linewidth',1,'EdgeColor',[0 0 1])
            line([8.7348,4],[5.2652,8],'linewidth',1,'color',[0 0 1])
        end
        
        function block = create_ff_block(app)
            block.P=rectangle('position',[7 5 2 1.5],'facecolor',[0.7 0.3 1.0]);
            block.Q=rectangle('position',[3 5 2 1.5],'facecolor',[1.0 1.0 0.0]);
            block.textP=text(7.5,5.75,'$P$','fontsize',14,'interpreter','latex','color',[1 1 1]);
            block.textQ=text(3.5,5.75,'$Q$','fontsize',14,'interpreter','latex','color',[0 0 0]);
            block.z(1)=line([10.5 11.0],[6.00 5.75],'linewidth',1,'color',[0 0 0]);
            block.z(2)=line([10.5 11.0],[5.50 5.75],'linewidth',1,'color',[0 0 0]);
            block.z(3)=line([9.0 11.0],[5.75 5.75] ,'linewidth',1,'color',[0 0 0]);
            block.v(1)=line([6.5 7.0] ,[6.00 5.75] ,'linewidth',1,'color',[1 0 0]);
            block.v(2)=line([6.5 7.0] ,[5.50 5.75] ,'linewidth',1,'color',[1 0 0]);
            block.v(3)=line([5.0 7.0] ,[5.75 5.75] ,'linewidth',1,'color',[1 0 0]);
            block.u(1)=line([2.5 3.0] ,[6.00 5.75] ,'linewidth',1,'color',[0 0 1]);
            block.u(2)=line([2.5 3.0] ,[5.50 5.75] ,'linewidth',1,'color',[0 0 1]);
            block.u(3)=line([1.0 3.0] ,[5.75 5.75] ,'linewidth',1,'color',[0 0 1]);
            rectangle('Position',[9.5 5.375 0.75 0.75],'Curvature',[1,1], ...
                'linewidth',1)
            line([9.875,9.875,11],[6.125,6.5,8],'linewidth',1,'color',[0 0 0])
            rectangle('position',[5.5 5.375 0.75 0.75],'Curvature',[1,1], ...
                'linewidth',1,'EdgeColor',[1 0 0])
            line([5.875,5.875,2],[5.375,4,3],'linewidth',1,'color',[1 0 0])
            rectangle('position',[1.5 5.375 0.75 0.75],'Curvature',[1,1], ...
                'linewidth',1,'EdgeColor',[0 0 1])
            line([1.875,1.875,1.5],[6.125,6.5,8],'linewidth',1,'color',[0 0 1])
        end
        
        % Update components that require runtime configuration
        function addRuntimeConfigurations(app)
            
            % Load data for component configuration
            componentData = load('DQ_App.mat');
            
            % Set component properties that require runtime configuration
            app.figure1.DeleteFcn = createCallbackFcn(app, @figure1_DeleteFcn, true);
            app.edit_ref.UserData = componentData.edit_ref.UserData;
            app.edit_finaltime.UserData = componentData.edit_finaltime.UserData;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function DQ_OpeningFcn(app, varargin)
            % --- Executes just before DQ is made visible.
            
            % Add runtime required configuration - Added by Migration Tool
            addRuntimeConfigurations(app);
            
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to DQ (see VARARGIN)
            
            % Choose default command line output for DQ
            handles.output = hObject;
            
            odqgui_input = find(strcmp(varargin, 'ODQ_Designer'));
            if ~isempty(odqgui_input)
                handles.odq_design = varargin{odqgui_input+1};
            else
                errordlg('Please design optimal dynamic quantizer','ERROR','modal');
                error('ODQ ERROR')
            end
            
            % Update handles structure
            guidata(hObject, handles);
            
            % UIWAIT makes DQ wait for user response (see UIRESUME)
            % uiwait(handles.figure1);
            
            odqdata=get(handles.odq_design,'UserData');
            G    = odqdata.G;
            Q    = odqdata.Q;
            d    = odqdata.d;
            gain = odqdata.gain;
            E    = odqdata.E;
            Hk   = odqdata.Hk;
            
            stb=odqstb(Q);
            if size(Q.a,1)>1000
                Einf='skipped';
            else
                Einf = odqcost(G,Q,d,inf);
            end
            staticQ.a  = 0;
            staticQ.b1 = zeros(1,size(G.c2,1));
            staticQ.b2 = zeros(1,size(G.c2,1));
            staticQ.c  = zeros(size(G.c2,1),1);
            E_upper = odqcost(G,staticQ,d,inf);
            
            E_low   = norm(G.c1*G.b2,inf)*d/2;
            
            if stb==1
                set(handles.val_stability,'string','STABLE')
            else
                set(handles.val_stability,'string','UNSTABLE')
            end
            set(handles.val_gain_wv,'string',gain.wv)
            set(handles.val_dim ,'string',size(Q.a,1));
            set(handles.val_ET  ,'string',E);
            set(handles.val_Einf,'string',Einf);
            set(handles.val_LE  ,'string',E_low);
            set(handles.val_UE  ,'string',E_upper)
            singular_xrange=0:size(Hk.S,1)-1;
            stairs(handles.axes_hnkl,singular_xrange,diag(Hk.S),'LineWidth',2);
            set(handles.axes_hnkl,'Yscale','log','YGrid','on');
            redline=zeros(1,size(Hk.S,1));
            for k=1:size(Hk.S,1)-1
                if Hk.S(k+1,k+1)/Hk.S(k,k)<0.1
                    redline(k)=1;
                end
            end
            if max(redline)==1
            axes(handles.axes_hnkl)
            singular_yrange=get(gca,'YLim');
                xredline=find(redline);
                for k=1:length(xredline)
                    line([xredline(k) xredline(k)],singular_yrange,'color',[1 0 0])
                end
            set(gca,'XTick',xredline,'XTickLabel',xredline);
            set(gcf,'menubar','none','toolbar','none')
            %set(gcf,'menubar','figure','toolbar','figure')
            end
            ylabel(handles.axes_u   ,'Quantizer input'  ,'FontSize',10)
            ylabel(handles.axes_v   ,'Quantizer output' ,'FontSize',10)
            ylabel(handles.axes_zQ  ,'Output','FontSize',10)
            ylabel(handles.axes_diff,'Output difference','FontSize',10)
            
            if strcmp(odqdata.connection,'ff') || strcmp(odqdata.connection,'GQ')
                set(handles.edit_x0_2,'enable','off');
            end
            
            axes(handles.axes_block);
            if strcmp(odqdata.connection,'ff')
                create_ff_block(app);
            elseif strcmp(odqdata.connection,'fbiq')
                create_fbiq_block(app);
            elseif strcmp(odqdata.connection,'fboq')
                create_fboq_block(app);
            elseif strcmp(odqdata.connection,'GQ')
                create_GQ_block(app);
            end
        end

        % Value changed function: edit_finaltime
        function edit_finaltime_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit_finaltime (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit_finaltime as text
            %        str2double(get(hObject,'String')) returns contents of edit_finaltime as a double
            kFinal=str2double(get(hObject,'String'));
            set(handles.slider_finaltime,'Value',kFinal)
            set(hObject,'UserData',kFinal)
        end

        % Callback function
        function figure1_DeleteFcn(app, event)
            % --- Executes during object deletion, before destroying properties.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to figure1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            odqdata=get(handles.odq_design,'UserData');
            odqdata.flg.DQopen=0;
            set(handles.odq_design,'UserData',odqdata);
            guidata(hObject,handles)
        end

        % Button pushed function: save_WS
        function save_WS_Callback(app, event)
            % --- Executes on button press in save_WS.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to save_WS (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            odqdata=get(handles.odq_design,'UserData');
            val_name=inputdlg('Variable name in workspace');
            if ~isempty(val_name{1})
                assignin('base',val_name{1},odqdata.Q)
                fprintf('Quantizer exported in Workspace!\n');
            end
        end

        % Button pushed function: save_file
        function save_file_Callback(app, event)
            % --- Executes on button press in save_file.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to save_file (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            odqdata=get(handles.odq_design,'UserData');
            val_name=inputdlg('Variable name in workspace');
            if ~isempty(val_name{1})
                eval([val_name{1} '=' 'odqdata.Q']);
                uisave(val_name{1})
            end
        end

        % Callback function
        function slider_finaltime_Callback(app, event)
            % --- Executes on slider movement.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to slider_finaltime (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'Value') returns position of slider
            %        get(hObject,'Min') and get(hObject,'Max') to determine range of slider
            kFinal=get(hObject,'Value');
            %set(handles.edit_finaltime,'String',kFinal,'UserData',kFinal)
        end

        % Button pushed function: start_sim
        function start_sim_Callback(app, event)
            % --- Executes on button press in start_sim.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to start_sim (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            odqdata = get(handles.odq_design,'UserData');
            G = odqdata.G;
            Q = odqdata.Q;
            d = odqdata.d;
            
            Rt=get(handles.edit_ref,'string');
            x0_P=get(handles.edit_x0,'string');
            x0_K=get(handles.edit_x0_2,'string');
            kFinal=str2double(get(handles.edit_finaltime,'string'));
            x  = zeros( size(G.a ,1) , kFinal+1 );
            xQ = zeros( size(G.a ,1) , kFinal+1 );
            z  = zeros( size(G.c1,1) , kFinal+1 );
            zQ = zeros( size(G.c1,1) , kFinal+1 );
            u  = zeros( size(G.c2,1) , kFinal+1 );
            uQ = zeros( size(G.c2,1) , kFinal+1 );
            xi = zeros( size(Q.a ,1) , kFinal+1 );
            v  = zeros( size(Q.c ,1) , kFinal+1 );
            
            k = 0:kFinal;
            flg.r_work  = 0;
            flg.x0_P_work = 0;
            flg.x0_K_work = 0;
            work_val = evalin('base','who');
            for i = 1:size(work_val)
                if strcmp(work_val(i,:),Rt)==1
                    r=evalin('base',Rt);
                    flg.r_work=1;
                end
                if strcmp(work_val(i,:),x0_P)==1
                    x0_P=evalin('base',x0_K);
                    flg.x0_P_work=1;
                end
                if strcmp(work_val(i,:),x0_K)==1
                    x0_K=evalin('base',x0_K);
                    flg.x0_K_work=1;
                end
            end
            if flg.r_work==0
                r = eval(Rt);
            end
            if isscalar(r)==1
                r = r*ones(1,kFinal+1);
            end
            if flg.x0_P_work==1;
                x0_P2 = x0_P;
            else
                x0_P2 = eval(x0_P);
            end
            if flg.x0_K_work==1;
                x0_K2 = x0_K;
            else
                x0_K2 = eval(x0_K);
            end
            if strcmp(odqdata.connection,'ff') || strcmp(odqdata.connection,'GQ')
                x(:,1)  = x0_P2;
                xQ(:,1) = x0_P2;
            else
                x(:,1)  = [x0_P2;x0_K2];
                xQ(:,1) = [x0_P2;x0_K2];
            end
            
            %%% start simulation %%%
            for i=1:kFinal+1
                z(:,i)  = G.c1*x(:,i)  + G.d1*r(:,i);
                zQ(:,i) = G.c1*xQ(:,i) + G.d1*r(:,i);
                u(:,i)  = G.c2*x(:,i)  + G.d2*r(:,i);
                uQ(:,i) = G.c2*xQ(:,i) + G.d2*r(:,i);
                v(:,i)  = d*round( ( Q.c*xi(:,i) + uQ(:,i) )/d );
                xi(:,i+1) = Q.a*xi(:,i) + Q.b1*uQ(:,i) + Q.b2*v(:,i);
                x(:,i+1)  = G.a*x(:,i)  + G.b1*r(:,i) + G.b2*u(:,i);
                xQ(:,i+1) = G.a*xQ(:,i) + G.b1*r(:,i) + G.b2*v(:,i);
            end
            stairs(handles.axes_u   ,k,u'    );
            %axis(handles.axes_u,[0 kFinal min(u) max(u)])
            stairs(handles.axes_v   ,k,v'    );
            stairs(handles.axes_zQ  ,k,zQ'   );
            stairs(handles.axes_diff,k,zQ'-z');
            ylabel(handles.axes_u   ,'Quantizer input'  ,'FontSize',10)
            ylabel(handles.axes_v   ,'Quantizer output' ,'FontSize',10)
            ylabel(handles.axes_zQ  ,'Output'           ,'FontSize',10)
            ylabel(handles.axes_diff,'Output difference','FontSize',10)
            set(handles.axes_u   ,'XGrid','on','YGrid','on')
            set(handles.axes_v   ,'XGrid','on','YGrid','on')
            set(handles.axes_zQ  ,'XGrid','on','YGrid','on')
            set(handles.axes_diff,'XGrid','on','YGrid','on')
        end

        % Button down function: axes_hnkl
        function axes_hnklButtonDown(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            colormap(app.figure1, 'parula');
            app.figure1.Name = 'Verification Window';
            app.figure1.Tag = 'figure1';
            app.uipanel2 = uipanel(app.figure1);
            app.uipanel2.Title = 'Designed Quantizer';
            app.uipanel2.Tag = 'uipanel2';
            app.axes_hnkl = uiaxes(app.uipanel2);
            app.axes_hnkl.CLim = [1 2];
            app.axes_hnkl.YScale = 'log';
            app.axes_hnkl.ButtonDownFcn = createCallbackFcn(app, @axes_hnklButtonDown, true);
            app.axes_hnkl.Tag = 'axes_hnkl';
            app.val_stability = uieditfield(app.uipanel2, 'text');
            app.val_stability.Tag = 'val_stability';
            app.val_stability.Enable = 'off';
            app.val_gain_wv = uieditfield(app.uipanel2, 'text');
            app.val_gain_wv.Tag = 'val_gain_wv';
            app.val_gain_wv.Enable = 'off';
            app.val_dim = uieditfield(app.uipanel2, 'text');
            app.val_dim.Tag = 'val_dim';
            app.val_dim.Enable = 'off';
            app.val_ET = uieditfield(app.uipanel2, 'text');
            app.val_ET.Tag = 'val_ET';
            app.val_ET.Enable = 'off';
            app.val_Einf = uieditfield(app.uipanel2, 'text');
            app.val_Einf.Tag = 'val_Einf';
            app.val_Einf.Enable = 'off';
            app.val_LE = uieditfield(app.uipanel2, 'text');
            app.val_LE.Tag = 'val_LE';
            app.val_LE.Enable = 'off';
            app.val_UE = uieditfield(app.uipanel2, 'text');
            app.val_UE.Tag = 'val_UE';
            app.val_UE.Enable = 'off';
            app.text2 = uilabel(app.uipanel2);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Dimention';
            app.text4 = uilabel(app.uipanel2);
            app.text4.Tag = 'text4';
            app.text4.Text = 'Stability';
            app.text3 = uilabel(app.uipanel2);
            app.text3.Tag = 'text3';
            app.text3.Text = {'Quantizer'; 'gain'};
            app.text7 = uilabel(app.uipanel2);
            app.text7.Tag = 'text7';
            app.text7.Text = 'E(T,Q)';
            app.text6 = uilabel(app.uipanel2);
            app.text6.Tag = 'text6';
            app.text6.Text = 'E(Inf,Q)';
            app.text5 = uilabel(app.uipanel2);
            app.text5.Tag = 'text5';
            app.text5.Text = {'Lower bound'; 'of E(Inf,Q)'};
            app.text8 = uilabel(app.uipanel2);
            app.text8.Tag = 'text8';
            app.text8.Text = {'Upper bound'; 'of E(Inf,Q)'};
            app.text9 = uilabel(app.uipanel2);
            app.text9.Tag = 'text9';
            app.text9.Text = 'Singular value distribution';
            app.val_gain_uv = uieditfield(app.uipanel2, 'text');
            app.val_gain_uv.Tag = 'val_gain_uv';
            app.val_gain_uv.Enable = 'off';
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Title = 'Simulation';
            app.uipanel1.Tag = 'uipanel1';
            app.axes_zQ = uiaxes(app.uipanel1);
            app.axes_zQ.Tag = 'axes_zQ';
            app.axes_u = uiaxes(app.uipanel1);
            app.axes_u.Tag = 'axes_u';
            app.axes_v = uiaxes(app.uipanel1);
            app.axes_v.Tag = 'axes_v';
            app.axes_diff = uiaxes(app.uipanel1);
            app.axes_diff.Tag = 'axes_diff';
            app.axes_block = uiaxes(app.uipanel1);
            app.axes_block.XLim = [0 12];
            app.axes_block.YLim = [3 8];
            app.axes_block.Tag = 'axes_block';
            app.axes_block.Visible = 'off';
            app.edit_ref = uieditfield(app.uipanel1, 'text');
            app.edit_ref.Tag = 'edit_ref';
            app.edit_ref.Value = 'sin(k)+0.5*cos(k)';
            app.edit_x0 = uieditfield(app.uipanel1, 'text');
            app.edit_x0.Tag = 'edit_x0';
            app.edit_x0.Value = '[0;0]';
            app.edit_x0_2 = uieditfield(app.uipanel1, 'text');
            app.edit_x0_2.Tag = 'edit_x0_2';
            app.edit_x0_2.Value = '[0;0]';
            app.edit_finaltime = uieditfield(app.uipanel1, 'text');
            app.edit_finaltime.ValueChangedFcn = createCallbackFcn(app, @edit_finaltime_Callback, true);
            app.edit_finaltime.Tag = 'edit_finaltime';
            app.edit_finaltime.Value = '100';
            app.start_sim = uibutton(app.uipanel1, 'push');
            app.start_sim.ButtonPushedFcn = createCallbackFcn(app, @start_sim_Callback, true);
            app.start_sim.Tag = 'start_sim';
            app.start_sim.Text = 'Start';
            app.text11 = uilabel(app.uipanel1);
            app.text11.Tag = 'text11';
            app.text11.Text = 'x(0) (Initial state)';
            app.text10 = uilabel(app.uipanel1);
            app.text10.Tag = 'text10';
            app.text10.Text = 'R[k] (Reference)';
            app.text12 = uilabel(app.uipanel1);
            app.text12.Tag = 'text12';
            app.text12.Text = 'Simlation Time';
            app.uipanel3 = uipanel(app.figure1);
            app.uipanel3.Title = 'E x p o r t';
            app.uipanel3.Tag = 'uipanel3';
            app.save_WS = uibutton(app.uipanel3, 'push');
            app.save_WS.ButtonPushedFcn = createCallbackFcn(app, @save_WS_Callback, true);
            app.save_WS.Tag = 'save_WS';
            app.save_WS.Text = 'To Workspace';
            app.save_file = uibutton(app.uipanel3, 'push');
            app.save_file.ButtonPushedFcn = createCallbackFcn(app, @save_file_Callback, true);
            app.save_file.Tag = 'save_file';
            app.save_file.Text = 'To File';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DQ_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)DQ_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end