classdef odqlab_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        odq_design                   matlab.ui.Figure
        ODQLabLabel                  matlab.ui.control.Label
        MessageTextArea              matlab.ui.control.TextArea
        MessageTextAreaLabel         matlab.ui.control.Label
        quantizer_deduction          matlab.ui.container.Panel
        re_design_button             matlab.ui.control.Button
        edit_sv                      matlab.ui.control.EditField
        QuantizerDementionEditFieldLabel  matlab.ui.control.Label
        QuantizerspecificationPanel  matlab.ui.container.Panel
        DesignButton                 matlab.ui.control.Button
        solvers                      matlab.ui.container.ButtonGroup
        radiobutton_mosek            matlab.ui.control.RadioButton
        radiobutton_gurobi           matlab.ui.control.RadioButton
        radiobutton_sedumi           matlab.ui.control.RadioButton
        radiobutton_sdpt3            matlab.ui.control.RadioButton
        radiobutton_cplex            matlab.ui.control.RadioButton
        radiobutton_linprog          matlab.ui.control.RadioButton
        QuantizergainPanel           matlab.ui.container.Panel
        edit_wv                      matlab.ui.control.EditField
        wvLabel                      matlab.ui.control.Label
        edit_uv                      matlab.ui.control.EditField
        uvEditFieldLabel             matlab.ui.control.Label
        EvaluationperiodPanel        matlab.ui.container.Panel
        edit_T                       matlab.ui.control.EditField
        QuantizationintervalPanel    matlab.ui.container.Panel
        edit_d                       matlab.ui.control.EditField
        connection                   matlab.ui.container.ButtonGroup
        import_P                     matlab.ui.control.Button
        plant_table                  matlab.ui.control.Table
        import_G                     matlab.ui.control.Button
        import_K                     matlab.ui.control.Button
        radiobutton_fboq             matlab.ui.control.RadioButton
        radiobutton_GQ               matlab.ui.control.RadioButton
        radiobutton_fbiq             matlab.ui.control.RadioButton
        radiobutton_ff               matlab.ui.control.RadioButton
        axes_GQ                      matlab.ui.control.UIAxes
        axes_fboq                    matlab.ui.control.UIAxes
        axes_ff                      matlab.ui.control.UIAxes
        axes_fbiq                    matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        odqdata
        systemG
        systemP
        systemK
        selectedSolver
        DQ

    end

    
    methods (Access = private)
        function block = create_GQ_block(app)
            block.G=rectangle(app.axes_GQ,'position',[5 6 2 1.5],'facecolor',[0.0 0.7 0.3]);
            block.Q=rectangle(app.axes_GQ,'position',[5 4.25 2 1.5],'facecolor',[1.0 1.0 0.0]);
            block.textG=text(app.axes_GQ,5.6,6.75,'$G$','fontsize',16,'interpreter','latex','color',[1 1 1]);
            block.textQ=text(app.axes_GQ,5.6,5.00,'$Q$','fontsize',16,'interpreter','latex','color',[0 0 0]);
            block.r(1)=line(app.axes_GQ,[ 4.50  5.00],[7.25 7.00],'linewidth',2,'color',[0 0 0]);
            block.r(2)=line(app.axes_GQ,[ 4.50  5.00],[6.75 7.00],'linewidth',2,'color',[0 0 0]);
            block.r(3)=line(app.axes_GQ,[ 1.00  5.00],[7.00 7.00],'linewidth',2,'color',[0 0 0]);
            block.u(1)=line(app.axes_GQ,[ 7.00  7.50],[5.00 5.25],'linewidth',2,'color',[0 0 1]);
            block.u(2)=line(app.axes_GQ,[ 7.00  7.50],[5.00 4.75],'linewidth',2,'color',[0 0 1]);
            block.u(3)=line(app.axes_GQ,[7.00 9.00 9.00 7.00],[ 6.50  6.50  5.00  5.00],'linewidth',2,'color',[0 0 1]);
            block.v(1)=line(app.axes_GQ,[ 4.50  5.00],[6.75 6.50],'linewidth',2,'color',[1 0 0]);
            block.v(2)=line(app.axes_GQ,[ 4.50  5.00],[6.25 6.50],'linewidth',2,'color',[1 0 0]);
            block.v(3)=line(app.axes_GQ,[ 5.00  3.00  3.00  5.00],[5.00  5.00  6.50  6.50],'linewidth',2,'color',[1 0 0]);
            block.z(1)=line(app.axes_GQ,[10.50 11.00],[7.25 7.00],'linewidth',2,'color',[0 0 0]);
            block.z(2)=line(app.axes_GQ,[10.50 11.00],[6.75 7.00],'linewidth',2,'color',[0 0 0]);
            block.z(3)=line(app.axes_GQ,[7.00 11.00],[7.00 7.00],'linewidth',2,'color',[0 0 0]);
        end
        
        function block = create_fbiq_block(app)
            block.P=rectangle(app.axes_fbiq,'position',[8 6 2 1.5],'facecolor',[0.7 0.3 1.0]);
            block.K=rectangle(app.axes_fbiq,'position',[2 6 2 1.5],'facecolor',[0.0 0.7 0.3]);
            block.Q=rectangle(app.axes_fbiq,'position',[5 6 2 1.5],'facecolor',[1.0 1.0 0.0]);
            block.textP=text(app.axes_fbiq,8.6,6.75,'$P$','fontsize',16,'interpreter','latex','color',[1 1 1]);
            block.textK=text(app.axes_fbiq,2.6,6.75,'$K$','fontsize',16,'interpreter','latex','color',[1 1 1]);
            block.textQ=text(app.axes_fbiq,5.6,6.75,'$Q$','fontsize',16,'interpreter','latex','color',[0 0 0]);
            block.r(1)=line(app.axes_fbiq,[ 1.50  2.00],[7.25 7.00],'linewidth',2,'color',[0 0 0]);
            block.r(2)=line(app.axes_fbiq,[ 1.50  2.00],[6.75 7.00],'linewidth',2,'color',[0 0 0]);
            block.r(3)=line(app.axes_fbiq,[ 0.00  2.00],[7.00 7.00],'linewidth',2,'color',[0 0 0]);
            block.u(1)=line(app.axes_fbiq,[ 4.50  5.00],[7.00 6.75],'linewidth',2,'color',[0 0 1]);
            block.u(2)=line(app.axes_fbiq,[ 4.50  5.00],[6.50 6.75],'linewidth',2,'color',[0 0 1]);
            block.u(3)=line(app.axes_fbiq,[ 4.00  5.00],[6.75 6.75],'linewidth',2,'color',[0 0 1]);
            block.v(1)=line(app.axes_fbiq,[ 7.50  8.00],[7.00 6.75],'linewidth',2,'color',[1 0 0]);
            block.v(2)=line(app.axes_fbiq,[ 7.50  8.00],[6.50 6.75],'linewidth',2,'color',[1 0 0]);
            block.v(3)=line(app.axes_fbiq,[ 7.00  8.00],[6.75 6.75],'linewidth',2,'color',[1 0 0]);
            block.z(1)=line(app.axes_fbiq,[11.50 12.00],[7.25 7.00],'linewidth',2,'color',[0 0 0]);
            block.z(2)=line(app.axes_fbiq,[11.50 12.00],[6.75 7.00],'linewidth',2,'color',[0 0 0]);
            block.z(3)=line(app.axes_fbiq,[10.00 12.00],[7.00 7.00],'linewidth',2,'color',[0 0 0]);
            block.y(1)=line(app.axes_fbiq,[ 1.50  2.00],[6.75 6.50],'linewidth',2,'color',[0 0 0]);
            block.y(2)=line(app.axes_fbiq,[ 1.50  2.00],[6.25 6.50],'linewidth',2,'color',[0 0 0]);
            block.y(3)=line(app.axes_fbiq,[10.00 10.50 10.50 1.25 1.25 2.00],[ 6.50  6.50  5.00 5.00 6.50 6.50],'linewidth',2,'color',[0 0 0]);
        end
        
        function block = create_fboq_block(app)
            block.P=rectangle(app.axes_fboq,'position',[8 6 2 1.5],'facecolor',[0.7 0.3 1.0]);
            block.K=rectangle(app.axes_fboq,'position',[2 6 2 1.5],'facecolor',[0.0 0.7 0.3]);
            block.Q=rectangle(app.axes_fboq,'position',[5 4.25 2 1.5],'facecolor',[1.0 1.0 0.0]);
            block.textP=text(app.axes_fboq,8.6,6.75,'$P$','fontsize',16,'interpreter','latex','color',[1 1 1]);
            block.textK=text(app.axes_fboq,2.6,6.75,'$K$','fontsize',16,'interpreter','latex','color',[1 1 1]);
            block.textQ=text(app.axes_fboq,5.6,5.00,'$Q$','fontsize',16,'interpreter','latex','color',[0 0 0]);
            block.r(1)=line(app.axes_fboq,[ 1.50  2.00],[7.25 7.00],'linewidth',2,'color',[0 0 0]);
            block.r(2)=line(app.axes_fboq,[ 1.50  2.00],[6.75 7.00],'linewidth',2,'color',[0 0 0]);
            block.r(3)=line(app.axes_fboq,[ 0.00  2.00],[7.00 7.00],'linewidth',2,'color',[0 0 0]);
            block.u(1)=line(app.axes_fboq,[ 7.00  7.50],[5.00 5.25],'linewidth',2,'color',[0 0 1]);
            block.u(2)=line(app.axes_fboq,[ 7.00  7.50],[5.00 4.75],'linewidth',2,'color',[0 0 1]);
            block.u(3)=line(app.axes_fboq,[10.00 10.50 10.50  7.00],[ 6.50  6.50  5.00  5.00],'linewidth',2,'color',[0 0 1]);
            block.v(1)=line(app.axes_fboq,[ 1.50  2.00],[6.75 6.50],'linewidth',2,'color',[1 0 0]);
            block.v(2)=line(app.axes_fboq,[ 1.50  2.00],[6.25 6.50],'linewidth',2,'color',[1 0 0]);
            block.v(3)=line(app.axes_fboq,[ 5.00  1.25  1.25  2.00],[5.00  5.00  6.50  6.50],'linewidth',2,'color',[1 0 0]);
            block.z(1)=line(app.axes_fboq,[11.50 12.00],[7.25 7.00],'linewidth',2,'color',[0 0 0]);
            block.z(2)=line(app.axes_fboq,[11.50 12.00],[6.75 7.00],'linewidth',2,'color',[0 0 0]);
            block.z(3)=line(app.axes_fboq,[10.00 12.00],[7.00 7.00],'linewidth',2,'color',[0 0 0]);
            block.w(1)=line(app.axes_fboq,[ 7.50  8.00],[7.00 6.75],'linewidth',2,'color',[0 0 0]);
            block.w(2)=line(app.axes_fboq,[ 7.50  8.00],[6.50 6.75],'linewidth',2,'color',[0 0 0]);
            block.w(3)=line(app.axes_fboq,[ 4.00  8.00],[6.75 6.75],'linewidth',2,'color',[0 0 0]);
        end
        
        function block = create_ff_block(app)
            block.P=rectangle(app.axes_ff,'position',[7 6 2 1.5],'facecolor',[0.7 0.3 1.0]);
            block.Q=rectangle(app.axes_ff,'position',[3 6 2 1.5],'facecolor',[1.0 1.0 0.0]);
            block.textP=text(app.axes_ff,7.6,6.75,'$P$','fontsize',16,'interpreter','latex','color',[1 1 1]);
            block.textQ=text(app.axes_ff,3.6,6.75,'$Q$','fontsize',16,'interpreter','latex','color',[0 0 0]);
            block.z(1)=line(app.axes_ff,[10.5 11.0],[7.00 6.75],'linewidth',2,'color',[0 0 0]);
            block.z(2)=line(app.axes_ff,[10.5 11.0],[6.50 6.75],'linewidth',2,'color',[0 0 0]);
            block.z(3)=line(app.axes_ff,[9.0 11.0],[6.75 6.75],'linewidth',2,'color',[0 0 0]);
            block.v(1)=line(app.axes_ff,[6.5 7.0],[7.00 6.75],'linewidth',2,'color',[1 0 0]);
            block.v(2)=line(app.axes_ff,[6.5 7.0],[6.50 6.75],'linewidth',2,'color',[1 0 0]);
            block.v(3)=line(app.axes_ff,[5.0 7.0],[6.75 6.75],'linewidth',2,'color',[1 0 0]);
            block.u(1)=line(app.axes_ff,[2.5 3.0],[7.00 6.75],'linewidth',2,'color',[0 0 1]);
            block.u(2)=line(app.axes_ff,[2.5 3.0],[6.50 6.75],'linewidth',2,'color',[0 0 1]);
            block.u(3)=line(app.axes_ff,[1.0 3.0],[6.75 6.75],'linewidth',2,'color',[0 0 1]);
        end


        % Update components that require runtime configuration
        function addRuntimeConfigurations(app)
            
            % Load data for component configuration
            componentData = load('odqlab_App.mat');
            
            % Set component properties that require runtime configuration
            app.connection.UserData = componentData.connection.UserData;
            app.axes_ff.UserData = componentData.axes_ff.UserData;
            app.axes_fbiq.UserData = componentData.axes_fbiq.UserData;
            app.axes_GQ.UserData = componentData.axes_GQ.UserData;
            app.axes_fboq.UserData = componentData.axes_fboq.UserData;
            app.plant_table.BackgroundColor = [1 1 1;0.9608 0.9608 0.9608];
            app.plant_table.ColumnFormat = {[] [] []};
            app.solvers.UserData = componentData.solvers.UserData;
            app.edit_T.UserData = componentData.edit_T.UserData;
            app.edit_uv.UserData = componentData.edit_uv.UserData;
            app.edit_wv.UserData = componentData.edit_wv.UserData;
            app.edit_d.UserData = componentData.edit_d.UserData;
        end
        
        
    end
    
    methods (Access = public)
        
        function [Q, E, Hk, gain] = odq2(app,G,T,d,gamma,dim,solver,connection)
        %ODQ Optimal Dynamic Quantizer
        %
        %Q = odq(G,T,d) is ODQ for G with evaluation interval 'T'.
        %Q = odq(G,T,d,gamma) is ODQ for G whose upper bound of gain is gamma, and
        %quantizing interval is d.
        %(gamma is structure which contains uv and wv.)
        %Q = odq(G,T,d,gamma,dim) can specify the dimension of Q. dim is the
        %dimension.
        %[Q E] = odq(G,T,d,gamma,dim) shows Q's cost.
        %[Q E Hk] = odq(G,T,d,gamma,dim) keeps Hankel matrix to workspace.
        %
        %Q = odq(G,T,d,gamma,dim,solver) specifies optimization solver.
        %'linprog' uses linprog. (Optimization Toolbox by Mathworks)
        %'cplex' uses CPLEX. (by ILOG http://www.ilog.com/)
        %CPLEX needs CPLEX MEX INTERFACE (contains some bugs)
        %
        %See also compg, odqreal, odqgain, odqcost, odqstb.
        
        if nargin==5
            if ischar(dim)
                solver = dim;
                dim=0;
            else
                solver='linprog';
            end
        elseif nargin==4
            if ischar(gamma)
                solver   = gamma;
                clear gamma
                gamma.wv = inf;
            else
                solver='linprog';
            end
            dim=0;
        elseif nargin==3
            gamma.wv = inf;
            dim=0;
            solver='linprog';
        elseif nargin<3
            error('there are not enough input argument')
        end
        
        %%%%%set dimension%%%%%
        m = size(G.c1*G.b2,2);
        p = size(G.c1*G.b2,1);
        %%%%%%%%%%%%%%%%%%%%%%%
        
        G.aa = G.a+G.b2*G.c2;    %convert to closed loop
        
        %%%%%%%%%%Compose Quantizer%%%%%%%%%%
        endflag=0;
        if T~=inf
            mstkn=1;
        else
            mstkn=0;
        end
        while (endflag == 0)
            if (m >= p && mstkn == 0)
                Q = odqanly2(app,G,connection,d,T);
                %Hk = 'null';
                Hk=odqhnkl_anly2(app,Q);
                if max(abs(eig(Q.a+Q.b2*Q.c)))<=1
                    endflag=1;
                else
                    mstkn = 0;
                    endflag=1;
                    disp('Try optimization...')
                end
            else
                [x, exitflag,Tnew,fval] = odqlp2(app,G,T,gamma,solver);
                if (exitflag==1)
                    Hk = odqhnkl2(app,x,Tnew,m,p);
                    if dim~=0
                        [Q, Hk] = odqreal(G,Hk,dim);
        
                    else
                        [Q, Hk] = odqreal(G,Hk);
                    end
                    endflag=2;
                else
                    error('Optimization is failed. Please change T and gamma')
                end
            end
        end
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        %%%%%Check Quantizer%%%%%
        
        if endflag==1
            %E=odqcost(G,Q,d,T);
            [Q,E]=odqanly2(app,G,connection,d,T);
        elseif endflag==2
            E=fval*d/2;
        end
        if (size(Q.a,1)>1000)
            disp('Q has too big dimension. Skip verifying')
            skipflag=1;
            gain.uv='skipped';
            gain.wv='skipped';
        else
            gain=odqgain(Q,T);
            skipflag=0;
        end
        %%%%%%%%%%%%%%%%%%%%%%%%%
        
        %%%%%%Display Result%%%%%
        fprintf('\n%s\n','RESULT')
        disp('connection')
        disp(connection)
        disp('Q')
        disp(Q)
        if (skipflag==1)
            fprintf('%s%d\n%s\n','T = ',T,'Computing gain and cost are skiped')
        else
            fprintf('%s%d\n%s%d\n%s%d\n','T = ',T,'gain uv = ',gain.uv,'gain wv = ',gain.wv)
            if E~=0
                fprintf('%s%1.4e%s%d%s\n','E = ',E,' in ',T,' steps.') 
            end
        end
        %%%%%%%%%%%%%%%%%%%%%%%%%
        end
        
        function [x,exitflag,T,fval] = odqlp2(~,G,T,gamma,solver)
        %ODQLP Optimal Dynamic Quantizer with LP
        %
        %This function is not to use alone.
        %Please use 'ODQ'.
        %
        %See also odq.
        
        %%%%%set parameters%%%%%
        if (mod(T,1)~=0)
            error('steptime must be a positive integer');
        end
        if (mod(T,2)==0)
            T=T+1;
        end
        if (gamma.wv<1)
            error('max gain w->v must be greater than 1');
        end
        
        m = size(G.c1*G.b2,2);
        p = size(G.c1*G.b2,1);
        
        G.aa = G.a+G.b2*G.c2;    %convert to closed loop
        %%%%%%%%%%%%%%%%%%%%%%%%
        
        %%%%%initialize%%%%%
        if strcmpi(solver,'linprog') || strcmpi(solver,'cplex') 
            if gamma.wv==inf
                vectorf = zeros( 1 + m*m*T + p*m*(T-1) , 1 );
                
            else
                vectorf = zeros( 1 + m*m*T*2 + p*m*(T-1) , 1 );
            end
        elseif strcmpi(solver,'sdpt3') || strcmpi(solver,'sedumi') 
            if gamma.wv==inf
                vectorf = zeros( 1 + m*m*T + p*m*(T-1) , 1 );
            else
                vectorf = sparse(zeros( 1 + m*m*T*2 + p*m*(T-1) + p + p*m*(T-1)*2 + m + m*m*T*2 , 1 ));
            end
        elseif  strcmpi(solver,'mosek')  || strcmpi(solver, 'gurobi')
            if gamma.wv==inf
                vectorf = zeros( 1 + m*m*T + p*m*(T-1) , 1 );
                
            else
                vectorf = zeros( 1 + m*m*T*2 + p*m*(T-1) , 1 );
            end
        else
            error('wrong solver')
        end
        %vectorb = zeros( p + p*m*(T-1)*2 + m + m*m*T*2 , 1 );
        %matrixA = zeros( p + p*m*(T-1)*2 + m + m*m*T*2 , 1 + m*m*T + p*m*(T-1) );
        %%%%%%%%%%%%%%%%%%%%
        
        vectorf(1)=1;
        
        fprintf('Preprocessing for LP...\n')
        pause(0.1)
        
        %%%%%making PHI%%%%%
        PHI     = zeros( m*p*(T-1) , m*m*T );   %initialize
        %PHIdash = zeros( p , m );               %initialize
        for i=1:T-1
            PHIdash = kron( G.c1*G.aa^(i-1)*G.b2 , eye(m) );
            for j=i:T-1
                PHI( (j-1)*m*p+1:j*m*p , (j-i)*m*m+1:(j-i+1)*m*m ) = PHIdash;
            end
        end
        %%%%%%%%%%%%%%%%%%%%
        
        %%%%%making 'matrix -> vector' transposer%%%%%
        eye_sumE = kron( ones(1,m*(T-1)) , eye(p) );
        eye_sumH = kron( ones(1,m*T) , eye(m) );
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        %%%%%%%%%%finalize%%%%%%%%%%
        if strcmpi(solver,'linprog') || strcmpi(solver,'cplex')
            if gamma.wv==inf
                matrixA = [
                    -ones( p , 1 )         zeros( p , m*m*T )  eye_sumE                 ;
                    zeros( p*m*(T-1) , 1 )   PHI              -eye( m*p*(T-1) )         ;
                    zeros( p*m*(T-1) , 1 )  -PHI              -eye( m*p*(T-1) )         ;
                    ];
            else
                matrixA = [
                    -ones( p , 1 )         zeros( p , m*m*T ) zeros( p , m*m*T )           eye_sumE                 ;
                    zeros( p*m*(T-1) , 1 )   PHI              zeros( p*m*(T-1) , m*m*T )  -eye( m*p*(T-1) )         ;
                    zeros( p*m*(T-1) , 1 )  -PHI              zeros( p*m*(T-1) , m*m*T )  -eye( m*p*(T-1) )         ;
                    zeros( m , 1 )         zeros( m , m*m*T )   eye_sumH                 zeros( m , m*p*(T-1) )     ;
                    zeros( m*m*T , 1 )       eye( m*m*T )      -eye( m*m*T )             zeros( m*m*T , m*p*(T-1) ) ;
                    zeros( m*m*T , 1 )      -eye( m*m*T )      -eye( m*m*T )             zeros( m*m*T , m*p*(T-1) ) ;
                    ];
            end
        elseif strcmpi(solver,'sdpt3') || strcmpi(solver,'sedumi')
            if gamma.wv==inf
                matrixA = [
                    -ones( p , 1 )         zeros( p , m*m*T )  eye_sumE                 ;
                    zeros( p*m*(T-1) , 1 )   PHI              -eye( m*p*(T-1) )         ;
                    zeros( p*m*(T-1) , 1 )  -PHI              -eye( m*p*(T-1) )         ;
                    ];
            else
                matrixA = sparse([
                    -ones( p , 1 )         zeros( p , m*m*T ) zeros( p , m*m*T )           eye_sumE                             eye(p) zeros(p        ,p*m*(T-1)) zeros(p        ,p*m*(T-1)) zeros(p        ,m) zeros(p        ,m*m*T) zeros(p        ,m*m*T);  % sum(E)-G <-abs(CB)
                    zeros( p*m*(T-1) , 1 )   PHI              zeros( p*m*(T-1) , m*m*T )  -eye( m*p*(T-1) )         zeros(p*m*(T-1),p)             eye(p*m*(T-1)) zeros(p*m*(T-1),p*m*(T-1)) zeros(p*m*(T-1),m) zeros(p*m*(T-1),m*m*T) zeros(p*m*(T-1),m*m*T);  %  PHI*H-E  <-C*A^k*B
                    zeros( p*m*(T-1) , 1 )  -PHI              zeros( p*m*(T-1) , m*m*T )  -eye( m*p*(T-1) )         zeros(p*m*(T-1),p) zeros(p*m*(T-1),p*m*(T-1))             eye(p*m*(T-1)) zeros(p*m*(T-1),m) zeros(p*m*(T-1),m*m*T) zeros(p*m*(T-1),m*m*T);  % -PHI*H-E <C*A^k*B
                    zeros( m , 1 )         zeros( m , m*m*T )   eye_sumH                 zeros( m , m*p*(T-1) )     zeros(m        ,p) zeros(m        ,p*m*(T-1)) zeros(m        ,p*m*(T-1))             eye(m) zeros(m        ,m*m*T) zeros(m        ,m*m*T);  % sum(Hbar)<gamma-1
                    zeros( m*m*T , 1 )       eye( m*m*T )      -eye( m*m*T )             zeros( m*m*T , m*p*(T-1) ) zeros(m*m*T    ,p) zeros(m*m*T    ,p*m*(T-1)) zeros(m*m*T    ,p*m*(T-1)) zeros(m*m*T    ,m)             eye(m*m*T) zeros(m*m*T    ,m*m*T);  % H-Hbar   <0
                    zeros( m*m*T , 1 )      -eye( m*m*T )      -eye( m*m*T )             zeros( m*m*T , m*p*(T-1) ) zeros(m*m*T    ,p) zeros(m*m*T    ,p*m*(T-1)) zeros(m*m*T    ,p*m*(T-1)) zeros(m*m*T    ,m) zeros(m*m*T    ,m*m*T)             eye(m*m*T);  % -H-Hbar  <0
                    ]);
            end
        elseif  strcmpi(solver,'mosek')|| strcmpi(solver, 'gurobi') 
            if gamma.wv==inf
                matrixA = sparse([
                    -ones( p , 1 )         zeros( p , m*m*T )  eye_sumE                 ;
                    zeros( p*m*(T-1) , 1 )   PHI              -eye( m*p*(T-1) )         ;
                    zeros( p*m*(T-1) , 1 )  -PHI              -eye( m*p*(T-1) )         ;
                    ]);
            else
                matrixA = sparse([
                    -ones( p , 1 )         zeros( p , m*m*T ) zeros( p , m*m*T )           eye_sumE                 ;
                    zeros( p*m*(T-1) , 1 )   PHI              zeros( p*m*(T-1) , m*m*T )  -eye( m*p*(T-1) )         ;
                    zeros( p*m*(T-1) , 1 )  -PHI              zeros( p*m*(T-1) , m*m*T )  -eye( m*p*(T-1) )         ;
                    zeros( m , 1 )         zeros( m , m*m*T )   eye_sumH                 zeros( m , m*p*(T-1) )     ;
                    zeros( m*m*T , 1 )       eye( m*m*T )      -eye( m*m*T )             zeros( m*m*T , m*p*(T-1) ) ;
                    zeros( m*m*T , 1 )      -eye( m*m*T )      -eye( m*m*T )             zeros( m*m*T , m*p*(T-1) ) ;
                    ]);
            end
        end
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        %%%%%making C A^k B ,changing matrix to vector%%%%%
        CAB = zeros( (T-1)*p , m );             %initialize
        el_CAB = zeros( m*p*(T-1) , 1 );        %initialize
        
        for i=1:T-1
            CAkB = G.c1*G.aa^i*G.b2;
            CAB( (i-1)*p+1:i*p , 1:m ) = CAkB;
        end
        
        for j=1:p*(T-1)
            for i=1:m
                el_CAB( i+(j-1)*m , 1 ) = CAB( j , i );
            end
        end
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        %%%%%%%%%%finalize%%%%%%%%%%
        if strcmpi(solver,'linprog') || strcmpi(solver,'cplex')  || strcmpi(solver,'mosek') || strcmpi(solver, 'gurobi')
            if gamma.wv==inf
                vectorb = [ 
                    -abs( G.c1*G.b2 )*ones( m , 1 ) ;
                    -el_CAB                         ;
                    el_CAB                          ;
                    ];
            else
                vectorb = [ 
                    -abs( G.c1*G.b2 )*ones( m , 1 ) ;
                    -el_CAB                         ;
                    el_CAB                          ;
                    (gamma.wv-1)*ones( m , 1 )      ;
                    zeros( m*m*T*2,1 )              ;
                    ];
            end
        elseif strcmpi(solver,'sdpt3') || strcmpi(solver,'sedumi') 
            if gamma.wv==inf
                vectorb = [
                    -abs( G.c1*G.b2 )*ones( m , 1 ) ;
                    -el_CAB                         ;
                    el_CAB                          ;
                    ];
            else
                vectorb = sparse([
                    -abs( G.c1*G.b2 )*ones( m , 1 ) ;
                    -el_CAB                         ;
                    el_CAB                          ;
                    (gamma.wv-1)*ones( m , 1 )      ;
                    zeros( m*m*T*2,1 )              ;
                    ]);
            end
        end
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        %%%%%%%%%% liner programing problem%%%%%%%%%%
        switch lower(solver)
            case 'linprog'
                fprintf('Solving LP problem (linplog)...\n')
                pause(0.1)
                options=optimset('LargeScale','on','Diagnostics','off','Display','off','MaxIter',10000,'TolFun',[]);
                [x,fval,exitflag,output]=linprog(vectorf,matrixA,vectorb,[],[],[],[],options);
                disp(output.message)
            case 'cplex'
                fprintf('Solving LP problem (cplex)...\n')
                pause(0.1)
                if ismac
                    pause(0.1)
                    [x,fval,STATUS]=lp_cplex(1,vectorf,matrixA,vectorb); %#ok<ASGLU>
                else
                    x=cplexlp(vectorf,matrixA,vectorb);
                    fval=vectorf'*x;
                end
                exitflag=1;
            case 'sdpt3'
                disp('Solving LP problem (SDPT3)...')
                blk{1,1}='l';
                blk{2,1}='u';
                blk{1,2}=m*m*T + m*p*(T-1) + p + p*m*(T-1)*2 + m + m*m*T*2;
                blk{2,2}=1+m*m*T;
                At{1}=sparse(matrixA(:,1+m*m*T+1:1 + m*m*T*2 + p*m*(T-1) + p + p*m*(T-1)*2 + m + m*m*T*2)');
                At{2}=sparse(matrixA(:,1:1+m*m*T)');
                C{1}=sparse(vectorf(1+m*m*T+1:1 + m*m*T*2 + p*m*(T-1) + p + p*m*(T-1)*2 + m + m*m*T*2));
                C{2}=sparse(vectorf(1:1+m*m*T));
                option_sdpt = sqlparameters;
                [obj,X,y,Z,info,runhist]=sqlp(blk,At,C,vectorb,option_sdpt); %#ok<ASGLU>
                x=[X{2};X{1}];
                fval=vectorf'*x;
                exitflag=1;
            case 'sedumi'
                disp('Solving LP problem (SeDuMi)...')
                K.f=1 + m*m*T;
                K.l= m*m*T + p*m*(T-1) + p + p*m*(T-1)*2 + m + m*m*T*2;
                x=sedumi(matrixA,vectorb,vectorf,K);
                length(vectorf)
                exitflag=1;
                fval=vectorf'*x;
            case 'gurobi'
                disp('Solving LP problem (Gurobi)...');
                pause(0.1);
                
                model.obj = vectorf;          % 目的関数の係数
                model.A = matrixA;            % 制約行列
                model.rhs = vectorb;          % 制約条件の右辺
                model.sense = '<';            % 制約条件の向き
                %model.lb = zeros(size(model.A,2), 1);
                model.ub = inf(size(model.A,2), 1);
                model.modelsense = 'min';     % 最小化問題
        
                %params.NumericFocus = 3;      % 数値安定性を重視
                %params.InfUnbdInfo = 1;       % 非実行可能の情報を取得
                %params.OutputFlag = 1;        % 出力表示オン
        
                %result = gurobi(model, params);
                result = gurobi(model);
        
                if strcmp(result.status, 'OPTIMAL')
                    x = result.x;
                    fval = vectorf' * x;
                    exitflag = 1;
                else
                    error('Gurobi failed to find an optimal solution.');
                end
            case 'mosek'
                disp('Solving LP problem (Mosek)...');
                
                prob.c = vectorf;           %目的関数
                prob.a = matrixA;           %制約行列
                prob.blc = -inf(size(prob.a,1), 1);    %制約の下限 (`≤` 制約なので -inf)
                prob.buc = vectorb;          %制約の上限 (`rhs`)
                %prob.blx = zeros(size(prob.a,2), 1);          %変数の下限
                prob.bux = inf(size(prob.a,2), 1);      %変数の上限 (なし)
        
                [r, res] = mosekopt('minimize', prob);
        
                if r == 0 
                    x = res.sol.bas.xx;     %変数 x の最適値
                    fval = vectorf' * x;
                    exitflag = 1;
                else
                    disp('MOSEK failed to find an optimal solution.');
                end
        end
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        end
        
        
        function Hk = odqhnkl2(app,x,T,m,p) %#ok<INUSD>
        %ODQHNKL Compute block hankel matrix from inpulse response matrix of ODQ
        %
        %This function is not to use alone.
        %Please use 'ODQREAL'.
        %
        %See also odqreal.
        
        disp('Computing SVD...')
        pause(0.1)
        
        Tdash=floor(T/2)+1;
        
        %%%%%making Block Hankel matrix%%%%%
        for i=1:m*T
            row_H( i , 1:m ) = x( 2+(i-1)*m : 1+i*m );
        end
        
        for i=1:Tdash
            Hk.H( 1:m*Tdash , (i-1)*m+1:i*m ) = row_H( (i-1)*m+1:(i+Tdash-1)*m , : );
        end
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        %%%%%Singular Value Decomposition%%%%%
        [Hk.Wo, Hk.S, Hk.Wc] = svd( Hk.H );
        Hk.Wc = Hk.Wc';
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        %%%%%%%%%%verify%%%%%%%%%%
        if norm(Hk.H-Hk.Wo*Hk.S*Hk.Wc,inf)/norm(Hk.H)>10^(-2)
            error('Oops! SVD is failed. Abort Program.')
        end
        %%%%%%%%%%%%%%%%%%%%%%%%%%
        end
        
        function H=odqhnkl_anly2(~,Q)
        
        nG = size(Q.a,1);  %Size of Q
        m  = size(Q.b1,2); %Size of input
        
        Tdash = floor(nG/2)+1;
        
        
        H2k = Q.c*Q.b2;
        row_H = H2k;
        for k=1:nG
            H2k = Q.c*(Q.a+Q.b2*Q.c)^k*Q.b2;
            row_H = [row_H; H2k];
        end
        
        for i=1:Tdash
            H.H(1:m*Tdash, (i-1)*m+1:i*m) = row_H((i-1)*m+1:(i+Tdash-1)*m, :);
        end
        
        [H.Wo, H.S, H.Wc] = svd( H.H );
        H.Wc = H.Wc';
        
        if norm(H.H-H.Wo*H.S*H.Wc,inf)/norm(H.H)>10^(-2)
            error('SVD is failed')
        end
        
        %hist(diag(H.S))
        end
        
        function [Q,E] = odqanly2(~,G,connection,d,T)
        %ODQANLY Optimal Dynamic Quantizer with analytic method
        %
        %This function is not to use alone.
        %Please use 'ODQ'.
        %
        %See also odq.
        
        % -------------------------------------------------------------------------
        % Copyright is with the following author. 
        % (C) 2008 Ryosuke Morita, 
        %          Kyoto University;
        %          Gokasho, Uji, Kyoto 611-0011, Japan
        %          morita@robot.kuass.kyoto-u.ac.jp
        % -------------------------------------------------------------------------
        % Legal Note:
        %           
        %     (a)  This program is a free software. 
        %          
        %     (b)  This program is distributed according to GNU General Public
        %          License, i.e., it is allowed to use WITHOUT ANY WARRANTY; 
        %          without even the implied warranty of
        %          MERCHANTABILITY or FITNESS FOR A PARTICULAR PURPOSE.  
        %          See the GNU General Public License for more details.
        % 
        % -------------------------------------------------------------------------
        
        G.aa = G.a+G.b2*G.c2;    %convert to closed loop
        
        nv_ff = size(G.b2,2);
        nz_ff = size(G.c1,1);
        nr = size(G.b1,2);
        nv = size(G.b2,2);
        nz = size(G.c1,1);
        nu = size(G.c2,1);
        
        if strcmpi(connection,'ff') && strcmpi(nv_ff,1) && strcmpi(nz_ff,1) 
            sys_G = ss(G.a, G.b2, G.c1, G.d1, 0.1);
            Ts = 0.1; %sampling time
            
            % Transfer function of the system
            tf_G = tf(sys_G);
        
            % Calculate zeros and poles of the transfer function
            zeros_ = tzero(tf_G);
            poles = pole(tf_G);
        
            % Identify unstable zeros (|z| > 1)
            unstable_zeros = zeros_(abs(zeros_) > 1);
        
        
            % Case 1: No unstable zeros
            if isempty(unstable_zeros)
                %{
                % Count time delay (poles at z=0)
                n_time_delay = sum(poles == 0);
                % Define z as a discrete-time variable
                num = 1;
                den = [1, zeros(1, n_time_delay)];
                G = tf(num,den,Ts);  % Time delay transfer function
                F = tf_G / G;  % Remaining part
                F_ss = ss(F);  % Convert to state-space representation
                B_F = F_ss.B;
                C_F = F_ss.C;
        
                % Compute quantization error
                E = norm(abs(C_F * B_F),"inf") * d/2;
                %}
        
                Q.a  = G.aa;
                Q.b1 = -G.b2;
                Q.b2 = G.b2;
                Q.c  = -pinv(G.c1*G.b2)*G.c1*G.aa;
        
                E = odqcost(G,Q,d,T);
        
        
            % Case 2: One unstable zero
            elseif numel(unstable_zeros) == 1
                i = numel(poles) - numel(zeros_);  % Relative order
                if i < 1
                    Q = [];
                    E = inf;
                    return
                end
                a = unstable_zeros(1);  % First unstable zero
                num = [1, -a];
                den = [1, zeros(1, i)];
                G = tf(num,den,Ts);  % Transfer function with unstable zero
                F = minreal(tf_G / G);  % Remaining part, simplified
                F_ss = ss(F);  % Convert to state-space representation
                B_F = F_ss.B;
                C_F = F_ss.C;
                
                % State-space matrices of F
                A_F = F_ss.A;
                D_F = F_ss.D;
            
                % Check conditions
                if abs(C_F * B_F) == 0
                  
                        disp('C_F * B_F == 0. Could not calculate quantizer.');
                  
                    Q = [];
                    E = inf;
                    return
                end
                if D_F ~= 0
                    
                        disp('D_F ~= 0. Could not calculate quantizer.');
                    
                    Q = [];
                    E = inf;
                    return
                end
                
                % Compute quantization error
                E = (1 + abs(a)) * abs(C_F * B_F) * d/2;
        
                % Construct dynamic quantizer Q
                Q.a = A_F;
                Q.b1 = -B_F;
                Q.b2 = B_F;
                Q.c = -pinv(C_F*B_F)*C_F*A_F;


            % Case 3: Multiple unstable zeros or unsupported cases
            else
                Q = [];
                E = inf;
                
                disp('Unsupported case: multiple unstable zeros or other issues.');
                return
            
            end


        elseif (strcmpi(connection,'GQ') || strcmpi(connection,'fbiq') ||strcmpi(connection,'fboq')) && nr==1 && nv==1 && nz==1 && nu==1
            sys_G1 = ss(G.aa, G.b2, G.c1, G.d1, 0.01);
            %sys_G2 = ss(G.a, G.b1+G.b2*G.d2, G.c1, G.d1, 0.1);
            Ts = 0.01;
            
            % Transfer function of the system
            tf_G1 = tf(sys_G1);
            %tf_G2 = tf(sys_G2);
        
            % Calculate zeros and poles of the transfer function
            %zeros_ = tzero(tf_G1);
            %poles = pole(tf_G1);
            [zeros_, poles, K] = zpkdata(tf_G1,'v');
        
            % Identify unstable zeros (|z| > 1)
            unstable_zeros = zeros_(abs(zeros_) > 1);
        
        
            % Case 1: No unstable zeros
            if isempty(unstable_zeros)
                
                %{
                F2 = pinv(G.c1*G.b2)*tf_G1;  
                F1 = G.c1*G.b2;  % Remaining part
                F1_ss = ss(F1);  % Convert to state-space representation
                F2_ss = ss(F2);
                B_F1 = F1_ss.B;
                C_F1 = F1_ss.C;
                A_F2 = F2_ss.A;
                B_F2 = F2_ss.B;
                C_F2 = F2_ss.C;
                D_F2 = F2_ss.D;
        
                % Compute quantization error
                sum_CAB = 0;
                for i=1:10000
                sum_CAB = sum_CAB + abs(C_F2*(A_F2)^(i-1)*B_F2);
                end
                E = (abs(D_F2) + sum_CAB)*d/2;
                %}
        
                Q.a  = G.aa;
                Q.b1 = -G.b2;
                Q.b2 = G.b2;
                Q.c  = -pinv(G.c1*G.b2)*G.c1*G.aa;
                
                E = odqcost(G,Q,d,T);
        
            % Case 2: One unstable zero
            elseif numel(unstable_zeros) == 1
                a = unstable_zeros(1);  % First unstable zero
                num = [K*1 K*(-a)];
                den = [1 0];
                F2 = tf(num,den,Ts);  % Transfer function with unstable zero
                F1 = minreal(tf_G1 / F2);  % Remaining part, simplified
                F1_ss = ss(F1);  % Convert to state-space representation
                F2_ss = ss(F2);
                B_F1 = F1_ss.B;
                C_F1 = F1_ss.C;
                A_F2 = F2_ss.A;
                B_F2 = F2_ss.B;
                C_F2 = F2_ss.C;
                D_F2 = F2_ss.D;
        
                % Compute quantization error
                sum_CAB = 0;
                for i=1:10000
                sum_CAB = sum_CAB + abs(C_F2*(A_F2)^(i-1)*B_F2);
                end
                E = (abs(D_F2) + sum_CAB)*d/2;
        
                % State-space matrices of F
                A_F1 = F1_ss.A;
                D_F1 = F1_ss.D;
            
                % Check conditions
                if abs(C_F1 * B_F1) == 0
                    
                        disp('C_F * B_F == 0. Could not calculate quantizer.');
                    
                    Q = [];
                    E = inf;
                    return
                end
                if D_F1 ~= 0
                    
                        disp('D_F ~= 0. Could not calculate quantizer.');
                    
                    Q = [];
                    E = inf;
                    return
                end
                % Construct dynamic quantizer Q
                Q.a = A_F1;
                Q.b1 = -B_F1;
                Q.b2 = B_F1;
                Q.c = -C_F1*A_F1;


            % Case 3: two unstable zero
            elseif numel(unstable_zeros) == 2 && sign(unstable_zeros(1)) ~= sign(unstable_zeros(2))
                a1 = unstable_zeros(1);  % First unstable zero
                a2 = unstable_zeros(2); % Second unstable zero
                num = [K*1 K*(-a1-a2) K*(-a1)*(-a2)];
                den = [1 0 0];
                F2 = tf(num,den,Ts);  % Transfer function with unstable zero
                F1 = minreal(tf_G1 / F2);  % Remaining part, simplified
                F1_ss = ss(F1);  % Convert to state-space representation
                F2_ss = ss(F2);
                B_F1 = F1_ss.B;
                C_F1 = F1_ss.C;
                A_F2 = F2_ss.A;
                B_F2 = F2_ss.B;
                C_F2 = F2_ss.C;
                D_F2 = F2_ss.D;
        
                % Compute quantization error
                sum_CAB = 0;
                for i=1:10000
                sum_CAB = sum_CAB + abs(C_F2*(A_F2)^(i-1)*B_F2);
                end
                E = (abs(D_F2) + sum_CAB)*d/2;
        
                % State-space matrices of F
                A_F1 = F1_ss.A;
                D_F1 = F1_ss.D;
            %{
                % Check conditions
                if abs(C_F1 * B_F1) ~= 1
                    
                        disp('C_F * B_F ~= 1. Could not calculate quantizer.');
                    
                    Q = [];
                    E = inf;
                    return
                end
            %}
                if D_F1 ~= 0
                    
                        disp('D_F ~= 0. Could not calculate quantizer.');
                    
                    Q = [];
                    E = inf;
                    return
                end
                % Construct dynamic quantizer Q
                Q.a = A_F1;
                Q.b1 = -B_F1;
                Q.b2 = B_F1;
                Q.c = -C_F1*A_F1;


             % % Case 3': three unstable zero
             % elseif numel(unstable_zeros) == 3
             %    a1 = unstable_zeros(1);  % First unstable zero
             %    a2 = unstable_zeros(2); % Second unstable zero
             %    a3 = unstable_zeros(3);
             %    num = [K*1 K*(-a1-a2-a3) K*((-a1)*(-a2)+(-a2)*(-a3)+(-a3)*(-a1)) K*(-a1)*(-a2)*(-a3)];
             %    den = [1 20.544 171.69 758.05 1681.16];
             %    F2 = tf(num,den,Ts);  % Transfer function with unstable zero
             %    F1 = minreal(tf_G1 / F2);  % Remaining part, simplified
             %    F1_ss = ss(F1);  % Convert to state-space representation
             %    F2_ss = ss(F2);
             %    B_F1 = F1_ss.B;
             %    C_F1 = F1_ss.C;
             %    A_F2 = F2_ss.A;
             %    B_F2 = F2_ss.B;
             %    C_F2 = F2_ss.C;
             %    D_F2 = F2_ss.D;
             %    F1_CB = abs(C_F1 * B_F1);
             % 
             % 
             %    % Compute quantization error
             %    sum_CAB = 0;
             %    for i=1:10000
             %    sum_CAB = sum_CAB + abs(C_F2*(A_F2)^(i-1)*B_F2);
             %    end
             %    E = (abs(D_F2) + sum_CAB)*d/2;
             % 
             % 
             %    % State-space matrices of F
             %    A_F1 = F1_ss.A;
             %    D_F1 = F1_ss.D;
             % 
             %    % Check conditions
             %    if abs(C_F1 * B_F1) ~= 1
             % 
             %            disp('C_F * B_F ~= 1. Could not calculate quantizer.');
             % 
             %        Q = [];
             %        E = inf;
             %        return
             %    end
             % 
             %    if D_F1 ~= 0
             % 
             %            disp('D_F ~= 0. Could not calculate quantizer.');
             % 
             %        Q = [];
             %        E = inf;
             %        return
             %    end
             %    % Construct dynamic quantizer Q
             %    Q.a = A_F1;
             %    Q.b1 = -B_F1;
             %    Q.b2 = B_F1;
             %    Q.c = -C_F1*A_F1;
                                
        
            % Case 3: Multiple unstable zeros or unsupported cases
            else
                Q = [];
                E = inf;
                
                disp('Unsupported case: multiple unstable zeros or other issues.');
                return
            end
        else
            Q.a  = G.aa;
            Q.b1 = -G.b2;
            Q.b2 = G.b2;
            Q.c  = -pinv(G.c1*G.b2)*G.c1*G.aa;
        
            E = odqcost(G,Q,d,T);
        end
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function odqlab_StartupFcn(app, odq_design)
            % --- Executes just before odqlab is made visible.
            
            % Add runtime required configuration - Added by Migration Tool
            addRuntimeConfigurations(app);
            
            % Ensure that the app appears on screen when run
            movegui(app.odq_design, 'onscreen');
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % app    structure with app and user data (see GUIDATA)
            % varargin   unrecognized PropertyName/PropertyValue pairs from the
            %            command line (see VARARGIN)
            
            % UIWAIT makes odqlab wait for user response (see UIRESUME)
            % uiwait(app.odq_design);
            app.odqdata.flg.ODQopen=1;
            app.odqdata.connection='ff';
            app.odq_design.UserData=app.odqdata;


            % Set axis limits
            app.axes_ff.XLim = [1 11];
            app.axes_ff.YLim = [4 9];
            
            % Draw initial block diagram
            app.create_ff_block();

            % Set axis limits
            app.axes_fbiq.XLim = [0 12];
            app.axes_fbiq.YLim = [3 8];
            
            % Draw initial block diagram
            app.create_fbiq_block();

            % Set axis limits
            app.axes_fboq.XLim = [0 12];
            app.axes_fboq.YLim = [3 8];
            
            % Draw initial block diagram
            app.create_fboq_block();

            % Set axis limits
            app.axes_GQ.XLim = [1 11];
            app.axes_GQ.YLim = [3 8];
            
            % Draw initial block diagram
            app.create_GQ_block();

            %app.plant_table.Data = double(3,1); % 空のセル配列を Data に追加
            rowNames = {'P','K','G'};
            app.plant_table.RowName = rowNames;
        end

        % Selection changed function: connection
        function connectionSelectionChanged(app, event)
            selectButton=event.NewValue;

            switch selectButton.Tag % Get Tag of selected object.
                case 'radiobutton_ff'
                    con='ff';
                case 'radiobutton_fbiq'
                    con='fbiq';
                case 'radiobutton_fboq'
                    con='fboq';
                case 'radiobutton_GQ'
                    con='GQ';
                % Continue with more cases as necessary.
                otherwise
                   con='null';
            end
            disp(con);
            app.odqdata.connection=con;
            app.connection.UserData=con; %remove the case "hObject=radiobuttonx"
            app.odq_design.UserData=app.odqdata;
        end

        % Value changed function: edit_T
        function edit_T_Callback(app, event)
            T=str2double(app.edit_T.Value);
            app.edit_T.UserData=T;
            disp(T);
        end

        % Value changed function: edit_d
        function edit_d_Callback(app, event)
            d=str2double(app.edit_d.Value);
            app.edit_d.UserData=d;
            disp(d);
        end

        % Value changed function: edit_sv
        function edit_sv_Callback(app, event)
            dim=str2double(app.edit_sv.Value);
            app.edit_sv.UserData=dim;
            disp(dim);
        end

        % Value changed function: edit_uv
        function edit_uv_Callback(app, event)
            gamma.uv=str2double(app.edit_uv.Value);
            app.edit_uv.UserData=gamma.uv;
            disp(gamma.uv);
        end

        % Value changed function: edit_wv
        function edit_wv_Callback(app, event)
            gamma.wv=str2double(app.edit_wv.Value);
            app.edit_wv.UserData=gamma.wv;
            disp(gamma.wv);
        end

        % Button pushed function: DesignButton
        function DesignButton_Callback(app, event)
            app.odqdata=app.odq_design.UserData;           
            flg=app.odqdata.flg;
            
            if isfield(flg,'DQopen')==1
                if flg.DQopen==1
                    delete(app.DQ)
                    flg.DQopen=0;
                end
            end
            %{
            if isfield(flg, 'DQopen') && flg.DQopen == 1
                if isvalid(app.DQ)
                    delete(app.DQ); % App Designerのアプリを削除
                end
                flg.DQopen = 0;
            end

            % DQ_Appを起動し、必要な引数を渡す
            app.DQ = DQ_App('ODQ_Designer', app.odq_design); 
            flg.DQopen = 1;
            %}
            
            con=app.connection.UserData;
            if strcmp(con,'GQ')
                % G=get(app.import_G,'UserData');
                G=app.odqdata.G;

            else
                P=app.odqdata.P;
                % P=get(app.import_P,'UserData');
                if strcmp(con,'ff')
                    K=[];
                else
                    % K=get(app.import_K,'UserData');
                    K=app.odqdata.K;
                end
                G=compg(P,K,con);
            end
            T=app.edit_T.UserData;
            d=app.edit_d.UserData;
            dim=app.edit_sv.UserData;
            gamma.uv=app.edit_uv.UserData;
            gamma.wv=app.edit_wv.UserData;
            solver=app.solvers.UserData;
            if size(G.c1*G.b2,2)<size(G.c1*G.b2,1) && T==inf
                % hint=textwrap(app.message_area,{'Could not derive analytic solution.',...
                %     'Please set Evaluation time for numerical optimization'});
                % set(app.message_area,'string',hint);
                app.MessageTextArea.Value=['Could not derive analytic solution.',...
                     'Please set Evaluation time for numerical optimization'];
            else
                if isnan(dim)
                [Q, E, Hk, gain]=odq2(app,G,T,d,gamma,[],solver,con);
                else
                [Q, E, Hk, gain]=odq2(app,G,T,d,gamma,dim,solver,con);
                end
                stb=odqstb(Q);
                app.odqdata.Q=Q;
                app.odqdata.E=E;
                app.odqdata.Hk=Hk;
                app.odqdata.gain=gain;
                app.odqdata.G=G;
                app.odqdata.d=d;
                app.odqdata.con=con;
                app.odq_design.UserData=app.odqdata;

                % データをまとめて他のアプリに渡すために保存
                myData.G = G;
                myData.T = T;
                myData.gamma = gamma;
                myData.Q = Q;
                myData.E = E;
                myData.Hk = Hk;
                myData.gain = gain;
                myData.d = d;
                myData.con = con;
                myData.odqdata = app.odqdata;
        
                save('DQ_App.mat', 'myData');
                
                if stb == 0
                    % メッセージ: NOT stable
                     app.MessageTextArea.Value = ['Optimal quantizer is NOT stable.\n', ...
                                     'Please set Evaluation time for numerical optimization.'];
                elseif size(Q.a,1) > size(G.a,1)
                     app.MessageTextArea.Value =['The Dimension of the quantizer is maybe too large.\n', ...
                                     'The following two approaches are useful to reduce dimension:\n', ...
                                     '1. Set small evaluation time OR\n', ...
                                     '2. Truncate small singular values.'];
                end
            
                %app.DQ=DQ('ODQ_Designer',app.odq_design);
                %flg.DQopen=1;

                if isempty(app.DQ) || ~isvalid(app.DQ)
                    app.DQ = DQ_App('ODQ_Designer', app.odq_design);
                    flg.DQopen = 1;
                else
                    figure(app.DQ.odq_varification); % すでに開いているならフォーカス
                end

            end
            app.odqdata.flg=flg;
            app.odq_design.UserData=app.odqdata;
        end

        % Button pushed function: import_G
        function import_G_Callback(app, event)
            % --- Executes on button press in import_G.
            
            app.systemG=system_setup_G(app.odq_design);
            uiwait(app.systemG.odq_design);
            app.odqdata=app.odq_design.UserData;
            
            if isfield(app.odqdata,'G')
                G=app.odqdata.G;
                if isstruct(G)
                    plantdata=app.plant_table.Data;
                    plantdata(3,1)=size(G.a,1);
                    plantdata(3,2)=size(G.b1,2);
                    plantdata(3,3)=size(G.c1,1);
                    app.plant_table.Data=plantdata;
                    drawnow;
                end
            end
        end

        % Button pushed function: import_K
        function import_K_Callback(app, event)
            % --- Executes on button press in import_K.
            
            app.systemK=system_setup_K(app.odq_design);
            uiwait(app.systemK.odq_design);
            app.odqdata=app.odq_design.UserData;

            if isfield(app.odqdata,'K')
                K=app.odqdata.K;
                if isstruct(K)
                    plantdata=app.plant_table.Data;
                    plantdata(2,1)=size(K.a,1);
                    plantdata(2,2)=size(K.b1,2);
                    plantdata(2,3)=size(K.c,1);
                    app.plant_table.Data=plantdata;
                    drawnow;
                end
            end
        end

        % Button pushed function: import_P
        function import_P_Callback(app, event)
            % --- Executes on button press in import_P.
            
            app.systemP=system_setup_P(app.odq_design);
            uiwait(app.systemP.odq_design);
            app.odqdata=app.odq_design.UserData;

            if isfield(app.odqdata,'P')
                P=app.odqdata.P;
                if isstruct(P)
                    plantdata=app.plant_table.Data;
                    plantdata(1,1)=size(P.a,1);
                    plantdata(1,2)=size(P.b,2);
                    plantdata(1,3)=size(P.c1,1);
                    app.plant_table.Data=plantdata;
                    drawnow;
                end
            end
        end

        % Button pushed function: re_design_button
        function re_design_button_Callback(app, event)
            % --- Executes on button press in re_design_button.
            
            % hObject    handle to re_design_button (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % app    structure with app and user data (see GUIDATA)
            %global Q E Hk gain G d
            app.odqdata=app.odq_design.UserData;
            flg=app.odqdata.flg;
            if isfield(flg,'DQopen')==1
                if flg.DQopen==1
                    delete(app.DQ)
                    flg.DQopen=0;
                end
            end

            con=app.connection.UserData;
            if strcmp(con,'GQ')
                % G=get(app.import_G,'UserData');
                G=app.odqdata.G;
            else
                P=app.odqdata.P;
                % P=get(app.import_P,'UserData');
                if strcmp(con,'ff')
                    K=[];
                else
                    % K=get(app.import_K,'UserData');
                    K=app.odqdata.K;
                end
                G=compg(P,K,con);
            end
            T = app.edit_T.UserData;
            d = app.edit_d.UserData;
            dim = app.edit_sv.UserData;
            %gamma.wv = get(app.edit_wv,'UserData');
            Hk = app.odqdata.Hk;
            [Q, Hk] = odqreal(G,Hk,dim);
            E = odqcost(G,Q,d,T);
            gain = odqgain(Q,T);
            stb=odqstb(Q);
            %{
            if stb==0
                hint=textwrap(app.message_area,{'Please set T and gamma',...
                    'for numerical optimization'});
                set(app.message_area,'string',hint)
            end
            %}
            if stb == 0
                     hint = sprintf(['Please set T and gamma',...
                     'for numerical optimization']);
                     app.message_area.Value = hint; % TextAreaのValueプロパティに設定
            end
            app.odqdata.Q=Q;
            app.odqdata.E=E;
            app.odqdata.Hk=Hk;
            app.odqdata.gain=gain;
            app.odqdata.G=G;
            app.odqdata.d=d;
            app.odq_design.UserData=app.odqdata;

            myData.G = G;
            myData.T = T;
            %myData.gamma = gamma;
            myData.Q = Q;
            myData.E = E;
            myData.Hk = Hk;
            myData.gain = gain;
            myData.d = d;
            myData.con = con;
            myData.odqdata = app.odqdata;
            save('DQ_App.mat', 'myData');

            app.DQ=DQ_App();
            flg.DQopen=1;
            app.odqdata.flg=flg;
            app.odq_design.UserData=app.odqdata;
        end

        % Selection changed function: solvers
        function solversSelectionChanged(app, event)
            selectedButton = event.NewValue;
        
            switch selectedButton.Tag  % Tagプロパティを設定することを推奨
                case 'radiobutton_linprog'
                    app.selectedSolver = 'linprog';
                case 'radiobutton_cplex'
                    app.selectedSolver = 'cplex';
                case 'radiobutton_sdpt3'
                    app.selectedSolver = 'sdpt3';
                case 'radiobutton_sedumi'
                    app.selectedSolver = 'sedumi';
                case 'radiobutton_gurobi'
                    app.selectedSolver = 'gurobi';
                case 'radiobutton_mosek'
                    app.selectedSolver = 'mosek';
                otherwise
                    app.selectedSolver = 'unknown';
            end
        
            disp(['solver: ', app.selectedSolver]);
            app.odqdata.solvers=app.selectedSolver;
            app.solvers.UserData=app.selectedSolver; %remove the case "hObject=radiobuttonx"
            app.odq_design.UserData=app.odqdata;
        end

        % Button down function: axes_ff
        function axes_ffButtonDown(app, event)
            cla(app.axes_ff);
            app.create_ff_block();
        end

        % Button down function: axes_GQ
        function axes_GQButtonDown(app, event)
            cla(app.axes_GQ);
            app.create_GQ_block();
        end

        % Button down function: axes_fbiq
        function axes_fbiqButtonDown(app, event)
            cla(app.axes_fbiq);
            app.create_fbiq_block();
        end

        % Button down function: axes_fboq
        function axes_fboqButtonDown(app, event)
            cla(app.axes_fboq);
            app.create_fboq_block();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.odq_design = uifigure('Visible', 'off');
            app.odq_design.Name = 'MATLAB App';
            app.connection = uibuttongroup(app.odq_design);
            app.connection.SelectionChangedFcn = createCallbackFcn(app, @connectionSelectionChanged, true);
            app.connection.Title = 'System description';
            app.axes_fbiq = uiaxes(app.connection);
            app.axes_fbiq.XTick = [];
            app.axes_fbiq.YTick = [];
            app.axes_fbiq.ButtonDownFcn = createCallbackFcn(app, @axes_fbiqButtonDown, true);
            app.axes_fbiq.Tag = 'axes_fbiq';
            app.axes_fbiq.Visible = 'off';
            app.axes_ff = uiaxes(app.connection);
            app.axes_ff.XLim = [1 11];
            app.axes_ff.YLim = [4 9];
            app.axes_ff.XTick = [];
            app.axes_ff.XTickLabel = '';
            app.axes_ff.YTick = [];
            app.axes_ff.YTickLabel = '';
            app.axes_ff.ButtonDownFcn = createCallbackFcn(app, @axes_ffButtonDown, true);
            app.axes_ff.Tag = 'axes_ff';
            app.axes_ff.Visible = 'off';
            app.axes_fboq = uiaxes(app.connection);
            app.axes_fboq.XTick = [];
            app.axes_fboq.YTick = [];
            app.axes_fboq.ButtonDownFcn = createCallbackFcn(app, @axes_fboqButtonDown, true);
            app.axes_fboq.Visible = 'off';
            app.axes_GQ = uiaxes(app.connection);
            app.axes_GQ.XTick = [];
            app.axes_GQ.YTick = [];
            app.axes_GQ.ButtonDownFcn = createCallbackFcn(app, @axes_GQButtonDown, true);
            app.axes_GQ.Tag = 'axes_GQ';
            app.axes_GQ.Visible = 'off';
            app.radiobutton_ff = uiradiobutton(app.connection);
            app.radiobutton_ff.Tag = 'radiobutton_ff';
            app.radiobutton_ff.Text = 'Feedforward connection';
            app.radiobutton_ff.Value = true;
            app.radiobutton_fbiq = uiradiobutton(app.connection);
            app.radiobutton_fbiq.Tag = 'radiobutton_fbiq';
            app.radiobutton_fbiq.Text = 'Feedback connection with input quantizer';
            app.radiobutton_GQ = uiradiobutton(app.connection);
            app.radiobutton_GQ.Tag = 'radiobutton_GQ';
            app.radiobutton_GQ.Text = 'LFT connection';
            app.radiobutton_fboq = uiradiobutton(app.connection);
            app.radiobutton_fboq.Tag = 'radiobutton_fboq';
            app.radiobutton_fboq.Text = 'Feedback connection with output quantizer';
            app.import_K = uibutton(app.connection, 'push');
            app.import_K.ButtonPushedFcn = createCallbackFcn(app, @import_K_Callback, true);
            app.import_K.Text = 'Set K';
            app.import_G = uibutton(app.connection, 'push');
            app.import_G.ButtonPushedFcn = createCallbackFcn(app, @import_G_Callback, true);
            app.import_G.Text = 'Set G';
            app.plant_table = uitable(app.connection);
            app.plant_table.ColumnName = {'Dimention'; 'Inputs'; 'Outputs'};
            app.plant_table.RowName = {};
            app.import_P = uibutton(app.connection, 'push');
            app.import_P.ButtonPushedFcn = createCallbackFcn(app, @import_P_Callback, true);
            app.import_P.Text = 'Set P';
            app.QuantizerspecificationPanel = uipanel(app.odq_design);
            app.QuantizerspecificationPanel.Title = 'Quantizer specification';
            app.QuantizationintervalPanel = uipanel(app.QuantizerspecificationPanel);
            app.QuantizationintervalPanel.Title = 'Quantization interval';
            app.edit_d = uieditfield(app.QuantizationintervalPanel, 'text');
            app.edit_d.ValueChangedFcn = createCallbackFcn(app, @edit_d_Callback, true);
            app.edit_d.Value = '1';
            app.EvaluationperiodPanel = uipanel(app.QuantizerspecificationPanel);
            app.EvaluationperiodPanel.Title = 'Evaluation period';
            app.edit_T = uieditfield(app.EvaluationperiodPanel, 'text');
            app.edit_T.ValueChangedFcn = createCallbackFcn(app, @edit_T_Callback, true);
            app.edit_T.Value = 'Inf';
            app.QuantizergainPanel = uipanel(app.QuantizerspecificationPanel);
            app.QuantizergainPanel.Title = 'Quantizer gain';
            app.uvEditFieldLabel = uilabel(app.QuantizergainPanel);
            app.uvEditFieldLabel.Text = 'u -> v';
            app.edit_uv = uieditfield(app.QuantizergainPanel, 'text');
            app.edit_uv.ValueChangedFcn = createCallbackFcn(app, @edit_uv_Callback, true);
            app.edit_uv.Tag = 'edit_uv';
            app.edit_uv.Value = 'Inf';
            app.wvLabel = uilabel(app.QuantizergainPanel);
            app.wvLabel.Text = 'w -> v';
            app.edit_wv = uieditfield(app.QuantizergainPanel, 'text');
            app.edit_wv.ValueChangedFcn = createCallbackFcn(app, @edit_wv_Callback, true);
            app.edit_wv.Tag = 'edit_wv';
            app.edit_wv.Value = 'Inf';
            app.solvers = uibuttongroup(app.QuantizerspecificationPanel);
            app.solvers.SelectionChangedFcn = createCallbackFcn(app, @solversSelectionChanged, true);
            app.solvers.Title = 'LP solvers';
            app.radiobutton_linprog = uiradiobutton(app.solvers);
            app.radiobutton_linprog.Tag = 'radiobutton_linprog';
            app.radiobutton_linprog.Text = 'linprog';
            app.radiobutton_linprog.Value = true;
            app.radiobutton_cplex = uiradiobutton(app.solvers);
            app.radiobutton_cplex.Tag = 'radiobutton_cplex';
            app.radiobutton_cplex.Text = 'CPLEX';
            app.radiobutton_sdpt3 = uiradiobutton(app.solvers);
            app.radiobutton_sdpt3.Tag = 'radiobutton_sdpt3';
            app.radiobutton_sdpt3.Text = 'SDPT3';
            app.radiobutton_sedumi = uiradiobutton(app.solvers);
            app.radiobutton_sedumi.Tag = 'radiobutton_sedumi';
            app.radiobutton_sedumi.Text = 'SeDuMi';
            app.radiobutton_gurobi = uiradiobutton(app.solvers);
            app.radiobutton_gurobi.Tag = 'radiobutton_gurobi';
            app.radiobutton_gurobi.Text = 'Gurobi';
            app.radiobutton_mosek = uiradiobutton(app.solvers);
            app.radiobutton_mosek.Tag = 'radiobutton_mosek';
            app.radiobutton_mosek.Text = 'MOSEK';
            app.DesignButton = uibutton(app.QuantizerspecificationPanel, 'push');
            app.DesignButton.ButtonPushedFcn = createCallbackFcn(app, @DesignButton_Callback, true);
            app.DesignButton.Text = 'Design';
            app.quantizer_deduction = uipanel(app.odq_design);
            app.quantizer_deduction.Title = 'Panel5';
            app.QuantizerDementionEditFieldLabel = uilabel(app.quantizer_deduction);
            app.QuantizerDementionEditFieldLabel.Text = {'Quantizer '; 'Demention'};
            app.edit_sv = uieditfield(app.quantizer_deduction, 'text');
            app.edit_sv.ValueChangedFcn = createCallbackFcn(app, @edit_sv_Callback, true);
            app.re_design_button = uibutton(app.quantizer_deduction, 'push');
            app.re_design_button.ButtonPushedFcn = createCallbackFcn(app, @re_design_button_Callback, true);
            app.re_design_button.Text = 'Reduce';
            app.MessageTextAreaLabel = uilabel(app.odq_design);
            app.MessageTextAreaLabel.Text = 'Message';
            app.MessageTextArea = uitextarea(app.odq_design);
            app.ODQLabLabel = uilabel(app.odq_design);
            app.ODQLabLabel.Text = 'ODQLab';
            % Show the figure after all components are created
            app.odq_design.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = odqlab_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.odq_design)

            % Execute the startup function
            runStartupFcn(app, @(app)odqlab_StartupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.odq_design)
        end
    end
end