classdef system_setup_G2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        set_G              matlab.ui.Figure
        OKButton           matlab.ui.control.Button
        CancelButton       matlab.ui.control.Button
        ParametersPanel    matlab.ui.container.Panel
        GridLayout         matlab.ui.container.GridLayout
        D2                 matlab.ui.control.EditField
        D2EditFieldLabel   matlab.ui.control.Label
        D1                 matlab.ui.control.EditField
        D1EditFieldLabel   matlab.ui.control.Label
        C1EditFieldLabel   matlab.ui.control.Label
        C2                 matlab.ui.control.EditField
        C2EditFieldLabel   matlab.ui.control.Label
        C1                 matlab.ui.control.EditField
        B2                 matlab.ui.control.EditField
        B2EditFieldLabel   matlab.ui.control.Label
        B1                 matlab.ui.control.EditField
        B1EditFieldLabel   matlab.ui.control.Label
        A                  matlab.ui.control.EditField
        AEditField_2Label  matlab.ui.control.Label
        SystemGPanel       matlab.ui.container.Panel
        equations          matlab.ui.control.Label
    end

    
    properties (Access = public)
        odq_design
        odqdata
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, odq_design)
            app.odq_design = odq_design;

            app.odqdata = app.odq_design.UserData;
            
            if isfield(app.odqdata, 'G')
                G = app.odqdata.G;
                if isstruct(G)
                    textG = app.odqdata.textG;
                    if isfield(G,'a')
                        app.A.Value = textG.a;
                    end
                    if isfield(G,'b1')
                        app.B1.Value = textG.b1;
                    end
                    if isfield(G,'b2')
                        app.B2.Value = textG.b2;
                   end
                    if isfield(G,'c1')
                        app.C1.Value = textG.c1;
                    end
                    if isfield(G,'c2')
                        app.C2.Value = textG.c2;
                    end
                    if isfield(G,'d1')
                        app.D1.Value = textG.d1;
                    end
                    if isfield(G,'d2')
                        app.D2.Value = textG.d2;
                    end
                end
            end
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            app.odqdata = app.odq_design.UserData;

            tmp_para.a  = app.A.Value;
            tmp_para.b1 = app.B1.Value;
            tmp_para.b2 = app.B2.Value;
            tmp_para.c1 = app.C1.Value;
            tmp_para.c2 = app.C2.Value;
            tmp_para.d1 = app.D1.Value;
            tmp_para.d2 = app.D2.Value;
        
            parameters.a  = evalin('base', tmp_para.a);
            parameters.b1 = evalin('base', tmp_para.b1);
            parameters.b2 = evalin('base', tmp_para.b2);
            parameters.c1 = evalin('base', tmp_para.c1);
            parameters.c2 = evalin('base', tmp_para.c2);
            parameters.d1 = evalin('base', tmp_para.d1);
            parameters.d2 = evalin('base', tmp_para.d2);
        
            app.odqdata.G = parameters;
            app.odqdata.textG = tmp_para;
        
            app.odq_design.UserData = app.odqdata;
            uiresume(app.odq_design);
            delete(app.set_G);
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
             app.odqdata = app.odq_design.UserData;

            if ~isfield(app.odqdata, 'G')
                app.odqdata.G = 'canceled';
            end
        
            app.odq_design.UserData = app.odqdata;
        
            delete(app.set_G);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.set_G = uifigure('Visible', 'off');
            app.set_G.Name = 'MATLAB App';
            app.SystemGPanel = uipanel(app.set_G);
            app.SystemGPanel.Title = 'System G';
            app.equations = uilabel(app.SystemGPanel);
            app.equations.Text = {'x [k+1]  =    A＊x [k]  +  B1＊r [k]  +  B2＊v [k]'; 'z [k]      =  C1＊x [k]  +  D1＊r [k]'; 'y [k]      =  C2＊x [k]  +  D2＊r [k]'};
            app.ParametersPanel = uipanel(app.set_G);
            app.ParametersPanel.Title = 'Parameters';
            app.GridLayout = uigridlayout(app.ParametersPanel);
            app.AEditField_2Label = uilabel(app.GridLayout);
            app.AEditField_2Label.Text = 'A:';
            app.A = uieditfield(app.GridLayout, 'text');
            app.A.Value = '[1 0.0822; 0 0.662]';
            app.B1EditFieldLabel = uilabel(app.GridLayout);
            app.B1EditFieldLabel.Text = 'B1:';
            app.B1 = uieditfield(app.GridLayout, 'text');
            app.B1.Value = '0';
            app.B2EditFieldLabel = uilabel(app.GridLayout);
            app.B2EditFieldLabel.Text = 'B2:';
            app.B2 = uieditfield(app.GridLayout, 'text');
            app.B2.Value = '[0.002; 0.041]';
            app.C1 = uieditfield(app.GridLayout, 'text');
            app.C1.Value = '[1 0]';
            app.C2EditFieldLabel = uilabel(app.GridLayout);
            app.C2EditFieldLabel.Text = 'C2:';
            app.C2 = uieditfield(app.GridLayout, 'text');
            app.C2.Value = '[-6.25 -3.30]';
            app.C1EditFieldLabel = uilabel(app.GridLayout);
            app.C1EditFieldLabel.Text = 'C1:';
            app.D1EditFieldLabel = uilabel(app.GridLayout);
            app.D1EditFieldLabel.Text = 'D1:';
            app.D1 = uieditfield(app.GridLayout, 'text');
            app.D1.Value = '0';
            app.D2EditFieldLabel = uilabel(app.GridLayout);
            app.D2EditFieldLabel.Text = 'D2:';
            app.D2 = uieditfield(app.GridLayout, 'text');
            app.D2.Value = '1';
            app.CancelButton = uibutton(app.set_G, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.OKButton = uibutton(app.set_G, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            % Show the figure after all components are created
            app.set_G.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = system_setup_G2(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.set_G)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.set_G)
        end
    end
end