classdef system_setup_K2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        set_K              matlab.ui.Figure
        OKButton           matlab.ui.control.Button
        CancelButton       matlab.ui.control.Button
        ParametersPanel    matlab.ui.container.Panel
        GridLayout         matlab.ui.container.GridLayout
        D2                 matlab.ui.control.EditField
        D2EditFieldLabel   matlab.ui.control.Label
        D1                 matlab.ui.control.EditField
        D1EditFieldLabel   matlab.ui.control.Label
        C                  matlab.ui.control.EditField
        CLabel             matlab.ui.control.Label
        C2                 matlab.ui.control.EditField
        C2EditFieldLabel   matlab.ui.control.Label
        B2                 matlab.ui.control.EditField
        B2EditFieldLabel   matlab.ui.control.Label
        B1                 matlab.ui.control.EditField
        B1EditFieldLabel   matlab.ui.control.Label
        A                  matlab.ui.control.EditField
        AEditField_2Label  matlab.ui.control.Label
        SystemKPanel       matlab.ui.container.Panel
        equations          matlab.ui.control.Label
    end

    
    properties (Access = public)
        odq_design
        odqdata
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, odq_design)
            app.odq_design = odq_design;

            app.odqdata = app.odq_design.UserData;
            
            if isfield(app.odqdata, 'K')
                K = app.odqdata.K;
                if isstruct(K)
                    textK = app.odqdata.textK;
                    if isfield(K,'a')
                        app.A.Value = textK.a;
                    end
                    if isfield(K,'b1')
                        app.B1.Value = textK.b1;
                    end
                    if isfield(K,'b2')
                        app.B2.Value = textK.b2;
                   end
                    if isfield(K,'c')
                        app.C.Value = textK.c;
                    end
                    if isfield(K,'d1')
                        app.D1.Value = textK.d1;
                    end
                    if isfield(K,'d2')
                        app.D2.Value = textK.d2;
                    end
                end
            end
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            app.odqdata = app.odq_design.UserData;

            tmp_para.a  = app.A.Value;
            tmp_para.b1 = app.B1.Value;
            tmp_para.b2 = app.B2.Value;
            tmp_para.c  = app.C.Value;
            tmp_para.d1 = app.D1.Value;
            tmp_para.d2 = app.D2.Value;
        
            parameters.a  = evalin('base', tmp_para.a);
            parameters.b1 = evalin('base', tmp_para.b1);
            parameters.b2 = evalin('base', tmp_para.b2);
            parameters.c  = evalin('base', tmp_para.c);
            parameters.d1 = evalin('base', tmp_para.d1);
            parameters.d2 = evalin('base', tmp_para.d2);
        
            app.odqdata.K = parameters;
            app.odqdata.textK = tmp_para;
        
            app.odq_design.UserData = app.odqdata;
            uiresume(app.odq_design);
            delete(app.set_K);
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
             app.odqdata = app.odq_design.UserData;

            if ~isfield(app.odqdata, 'K')
                app.odqdata.k = 'canceled';
            end
        
            app.odq_design.UserData = app.odqdata;
        
            delete(app.set_K);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.set_K = uifigure('Visible', 'off');
            app.set_K.Name = 'MATLAB App';
            app.SystemKPanel = uipanel(app.set_K);
            app.SystemKPanel.Title = 'System K';
            app.equations = uilabel(app.SystemKPanel);
            app.equations.Text = {'x [k+1]  =   A＊x [k]  +  B1＊r [k]  +  B2＊y [k]'; 'u [k]      =  C＊x [k]  +  D1＊r [k]  +  D2＊y [k]'};
            app.ParametersPanel = uipanel(app.set_K);
            app.ParametersPanel.Title = 'Parameters';
            app.GridLayout = uigridlayout(app.ParametersPanel);
            app.AEditField_2Label = uilabel(app.GridLayout);
            app.AEditField_2Label.Text = 'A:';
            app.A = uieditfield(app.GridLayout, 'text');
            app.A.Value = '1';
            app.B1EditFieldLabel = uilabel(app.GridLayout);
            app.B1EditFieldLabel.Text = 'B1:';
            app.B1 = uieditfield(app.GridLayout, 'text');
            app.B1.Value = '[0 0 0.01 0]';
            app.B2EditFieldLabel = uilabel(app.GridLayout);
            app.B2EditFieldLabel.Text = 'B2:';
            app.B2 = uieditfield(app.GridLayout, 'text');
            app.B2.Value = '-[0 0 0.01 0]';
            app.C2EditFieldLabel = uilabel(app.GridLayout);
            app.C2EditFieldLabel.Text = 'C2:';
            app.C2 = uieditfield(app.GridLayout, 'text');
            app.CLabel = uilabel(app.GridLayout);
            app.CLabel.Text = 'C:';
            app.C = uieditfield(app.GridLayout, 'text');
            app.C.Value = '112.46';
            app.D1EditFieldLabel = uilabel(app.GridLayout);
            app.D1EditFieldLabel.Text = 'D1:';
            app.D1 = uieditfield(app.GridLayout, 'text');
            app.D1.Value = '0';
            app.D2EditFieldLabel = uilabel(app.GridLayout);
            app.D2EditFieldLabel.Text = 'D2:';
            app.D2 = uieditfield(app.GridLayout, 'text');
            app.D2.Value = '-[451.92 58.40 351.71 89.31]';
            app.CancelButton = uibutton(app.set_K, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.OKButton = uibutton(app.set_K, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            % Show the figure after all components are created
            app.set_K.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = system_setup_K2(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.set_K)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.set_K)
        end
    end
end