classdef system_setup_P2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        set_P              matlab.ui.Figure
        OKButton           matlab.ui.control.Button
        CancelButton       matlab.ui.control.Button
        ParametersPanel    matlab.ui.container.Panel
        GridLayout         matlab.ui.container.GridLayout
        C2                 matlab.ui.control.EditField
        C1                 matlab.ui.control.EditField
        C1EditFieldLabel   matlab.ui.control.Label
        C2EditFieldLabel   matlab.ui.control.Label
        B                  matlab.ui.control.EditField
        BLabel             matlab.ui.control.Label
        A                  matlab.ui.control.EditField
        AEditField_2Label  matlab.ui.control.Label
        SystemPPanel       matlab.ui.container.Panel
        equations          matlab.ui.control.Label
    end

    
    properties (Access = public)
        odq_design
        odqdata
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, odq_design)
            app.odq_design = odq_design;

            app.odqdata = app.odq_design.UserData;

            A = [0 1 0 0; -1.92 -4.16 5.38 0; 0 0 0 1; 25.63 1.27 9.92 0];
            B = [0; 0.51; 0; -0.15];
            C1 = [0 0 1 0];
            C2 = eye(4);
            % [0.9999 0.0098 0.00026533 0.00000088745; -0.0188 0.9592 0.0527 0.00026533; 0.0013 0.000066861 1.0005 0.01; 0.2562 0.0137 0.0996 1.0005]
            % [0.000025149; 0.005; -0.0000073884; -0.0015] 

            sys_P = ss(A, B, C1, 0);
            sys_P2 = ss(A, B, C2, 0);
            sys_P = c2d(sys_P,0.01);
            sys_P3 = c2d(sys_P2,0.01);
    
            P.a = sys_P.A;
            P.b = sys_P.B;
            P.c1 = sys_P.C;
            P.c2 = eye(4);
            
            if isfield(app.odqdata, 'P')
                P = app.odqdata.P;
                if isstruct(P)
                    textP = app.odqdata.textP;
                    if isfield(P,'a')
                        app.A.Value = P.a;
                    end
                    if isfield(P,'b')
                        app.B.Value = P.b;
                    end
                    if isfield(P,'c1')
                        app.C1.Value = textP.c1;
                    end
                    if isfield(P,'c2')
                        app.C2.Value = textP.c2;
                    end
                end
            end
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            app.odqdata = app.odq_design.UserData;

            tmp_para.a  = app.A.Value;
            tmp_para.b = app.B.Value;
            tmp_para.c1 = app.C1.Value;
            tmp_para.c2 = app.C2.Value;
        
            parameters.a  = evalin('base', tmp_para.a);
            parameters.b  = evalin('base', tmp_para.b);
            parameters.c1 = evalin('base', tmp_para.c1);
            parameters.c2 = evalin('base', tmp_para.c2);

            app.odqdata.P = parameters;
            app.odqdata.textP = tmp_para;
        
            app.odq_design.UserData = app.odqdata;
            uiresume(app.odq_design);
            delete(app.set_P);
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
             app.odqdata = app.odq_design.UserData;

            if ~isfield(app.odqdata, 'P')
                app.odqdata.P = 'canceled';
            end
        
            app.odq_design.UserData = app.odqdata;
        
            delete(app.set_P);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.set_P = uifigure('Visible', 'off');
            app.set_P.Name = 'MATLAB App';
            app.SystemPPanel = uipanel(app.set_P);
            app.SystemPPanel.Title = 'System P';
            app.equations = uilabel(app.SystemPPanel);
            app.equations.Text = {'x [k+1]  =    A＊x [k]  +  B＊v [k]'; 'z [k]      =  C1＊x [k]'; 'u [k]      =  C2＊x [k]'};
            app.ParametersPanel = uipanel(app.set_P);
            app.ParametersPanel.Title = 'Parameters';
            app.GridLayout = uigridlayout(app.ParametersPanel);
            app.AEditField_2Label = uilabel(app.GridLayout);
            app.AEditField_2Label.Text = 'A:';
            app.A = uieditfield(app.GridLayout, 'text');
            app.A.Value = 'P.a';
            app.BLabel = uilabel(app.GridLayout);
            app.BLabel.Text = 'B:';
            app.B = uieditfield(app.GridLayout, 'text');
            app.B.Value = 'P.b';
            app.C2EditFieldLabel = uilabel(app.GridLayout);
            app.C2EditFieldLabel.Text = 'C2:';
            app.C1EditFieldLabel = uilabel(app.GridLayout);
            app.C1EditFieldLabel.Text = 'C1:';
            app.C1 = uieditfield(app.GridLayout, 'text');
            app.C1.Value = '[0 0 1 0]';
            app.C2 = uieditfield(app.GridLayout, 'text');
            app.C2.Value = 'eye(4)';
            app.CancelButton = uibutton(app.set_P, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.OKButton = uibutton(app.set_P, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            % Show the figure after all components are created
            app.set_P.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = system_setup_P2(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.set_P)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.set_P)
        end
    end
end